classdef tapCheck_change < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SingleLineCheckBox             matlab.ui.control.CheckBox
        MethodDropDown                 matlab.ui.control.DropDown
        WhichmethodDropDownLabel       matlab.ui.control.Label
        CurrentTapSpinner              matlab.ui.control.Spinner
        CurrentTapSpinnerLabel         matlab.ui.control.Label
        CurrentDepthSpinner            matlab.ui.control.Spinner
        CurrentDepthSpinnerLabel       matlab.ui.control.Label
        ROIDepthButton                 matlab.ui.control.Button
        TapSpinner                     matlab.ui.control.Spinner
        HowmanytapsSpinnerLabel        matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        NumLinesSpinner                matlab.ui.control.Spinner
        HowmanydepthlinesSpinnerLabel  matlab.ui.control.Label
        overTapAxes                    matlab.ui.control.UIAxes
        waveWidthAxes                  matlab.ui.control.UIAxes
        imAxes                         matlab.ui.control.UIAxes
    end


    properties (Access = private)
        Data % Description
        Params % Description
        Plot % Description
    end

    methods (Access = private)

        function results = LoupasDisplacement(app)

            M = 5;
            N = 5;

            f_cutoff = 1600;

            c_sound = 1540;%app.US.Resource.Parameters.speedOfSound;
            fc = app.Params.Fprobe;
            Fs = app.Params.Ffast;

            displ = nan(app.Data.Depth-M-1,app.Data.Width,app.Data.nAcq-N-1,app.Data.nTaps);
            displ_filt = nan(app.Data.Depth-M-1,app.Data.Width,app.Data.nAcq-N-1,app.Data.nTaps);

            %             fw = waitbar(0,sprintf('Processing %s',app.files.current));

            %             tic
            for ii = app.Params.tap_look
                %                 waitbar(ii/nTaps,fw,sprintf('Processing %s',app.files.current))
                I = app.Data.I(:,:,:,ii);
                Q = app.Data.Q(:,:,:,ii);

                for d = app.Params.I_dep_look %1: Depth-M-1 %all depth samples

                    for fr = 1:app.Data.nAcq - N-1 % along the time axis, in terms of frames
                        m = d:d+M;
                        n = fr:fr+N;

                        num1  =  Q(m,:,n)  .* I(m,:,n+1)  - I(m,:,n)  .* Q(m,:,n+1);
                        den1  =  I(m,:,n)  .* I(m,:,n+1)  + Q(m,:,n)  .* Q(m,:,n+1);
                        num2  =  Q(m,:,n)  .* I(m+1,:,n)  - I(m,:,n)  .* Q(m+1,:,n);
                        den2  =  I(m,:,n)  .* I(m+1,:,n)  + Q(m,:,n)  .* Q(m+1,:,n);

                        toptan = atan2( sum(sum(num1,1),3), sum(sum(den1,1),3));
                        bottan = atan2( sum(sum(num2,1),3), sum(sum(den2,1),3));

                        displ(d,:,fr,ii) = c_sound / (4*pi()*fc) *  toptan  ./  (1+bottan/(2*pi()));


                    end

                end


                for w = 1:app.Data.Width

                    % Low pass filter the velocity
                    displ_filt(app.Params.I_dep_look,w,:,ii)  = detrend(LowPassButterworth(squeeze(displ(app.Params.I_dep_look,w,:,ii)),4,f_cutoff,Fs)')';

                end

            end
            app.Data.raw = displ;
            app.Data.filt = displ_filt;

            filterDisplacement(app);
            %             app.Data.filt = displ_filt;

            %             waitbar(1,fw,'Complete!')
            %             close(fw)
        end

        function filterDisplacement(app)

            [Depth,Width,Time,~] = size(app.Data.raw);


            pf = 10; % padding factor


            for tp = app.Params.tap_look
                for d = app.Params.I_dep_look
                    fft_var = squeeze(app.Data.filt(d,:,:,tp));

                    % set up the filter
                    filtt = true(Width*pf,Time*pf);

                    filtt(Width*pf/2:end,1:Time*pf/2) = 0;
                    filtt(1:Width*pf/2,Time*pf/2:end) = 0;
                    filtt(1:end,1)  = 0;
                    filtt(1:end,end) = 0;
                    filtt(1,1:end) = 0;
                    filtt(end,1:end) = 0;

                    Y = (fft2(fft_var,Width*pf,Time*pf));

                    Y_filt = filtt.*Y;


                    fft_filt = ifft2(Y_filt,Width*pf,Time*pf);
                    app.Data.filt_fft(d,:,:,tp) = real(fft_filt(1:Width,1:Time));
                end
            end
        end

        function calculateSurfacePeaks(app)

            [Depth,Width,Frames,~] = size(app.Data.raw);
            dt = 1/(app.Params.Ffast)*10^3 ;
            tx = 0:dt :(Frames-1) * dt; %ms

            fw = waitbar(0,'Fitting curves...');


            %  x0 = [pulse_centre, pulse_width,pulse_slope,pulse_decay, pk_mag, pk_width,pk_offset]
            surf_func = @(pc,pw,ps,pd,mag,pkw,pko,t,w) mag .* sin(pkw*(t)  - (ps*8.1)*w - pko) .*...
                exp(-((t-(ps*w)-pc).^2)/((2.*pw).^2)).*(-pd*w+1);
            pulse_surf = fittype(surf_func,'independent',{'t','w'}); % time and width; width is normalised from 0 to 1

            wn = [1:Width]/Width; % normalized width

            [Tx,Wn] = meshgrid(tx,wn);

            tx_up = linspace(tx(1),tx(end),5000); % for upsampling
            [Tx_up,Wn_up] = meshgrid(tx_up,wn);

            % constant guesses for the gaussian (note pulse = gauss)
            % NONE are constant currently

            % constant estimates for the sine waves (note pk = sine)
            pk_width = 8; % pk_width/pi() = approximately how many sine waves are within the pulse
            pk_offset = -4; % shifting of where the sine wave starts within the pulse

            t_ind = 1; % to count taps

            nTaps = length(app.Params.tap_look);
            for tp = app.Params.tap_look

                wave_arrival_time = nan(Depth,Width);


                for d = app.Params.I_dep_look

                    waitbar(((t_ind-1)*Depth+d)/(Depth*nTaps),fw,'Fitting curves...');
                    displ_dep = squeeze(app.Data.filt_fft(d,:,:,tp));

                    envv = medfilt2(envelope(displ_dep',25,"analytic")',[15 5]);
                    [Vmax,Ipk] = max(envv,[],2); % get the peak of the envelope to centre the curve fit
                    time_centre = tx(Ipk);
                    prc = prctile(envv,50,'all');
                    Ib =sum(imbinarize(envv,prc),2); % basically gives the number of indices above the 50th percentile, gives an idea of the pulse width

                    pulse_width = sqrt(max(Ib)*dt)/2; % an estimation of the gaussian pulse width
                    pulse_centre = time_centre(1); % where the pulse is centred
                    pulse_slope = -range(time_centre); % the approximate time difference between the first and last peak
                    pulse_decay = (1-range(Vmax)/max(Vmax)); % attenuation over the width, currently linear

                    pk_mag = max(Vmax)*10^6; % the sine amplitude > has to be made larger to make the optimizer work

                    % initial surface parameters
                    x0 = [pulse_centre, pulse_width,pulse_slope,pulse_decay, pk_mag, pk_width,pk_offset];
                    x0(1) = pulse_centre;
                    x0(3) = pulse_slope;
                    x0(4) = pulse_decay; % decay
                    x0(5) = pk_mag; % peak magnitude

                    % reasonable bounds for solutions -> lots of tuning
                    % done here
                    x0_lower = [pulse_centre+pulse_slope ,0.05, -2.5,  0,  pk_mag/4,    4, -20];
                    x0_upper = [pulse_centre-pulse_slope ,0.8,  -0.2,   1, pk_mag*3,    12, 0 ];


                    [fitted_curve,gof,~] = fit([Tx(:),Wn(:)],displ_dep(:)*10^6,pulse_surf,'StartPoint',x0,'tolfun',10e-8,'Lower',x0_lower,'Upper',x0_upper);

                    surf_vals = fitted_curve(Tx_up,Wn_up);
                    %                     [pk_max,~] = max(surf_vals,[],2);
                    pk_rng = range(surf_vals');
                    for w = 1:Width
                        [~,locs,~,~] = findpeaks(surf_vals(w,:),'NPeaks',1,'MinPeakProminence',pk_rng(w)/3);%,'MinPeakHeight',pk_max(w)/4);
                        %             figure(10); plot(surf_vals(w,:));hold on; plot(locs,pks,'v');hold off;
                        if length(locs) < 1
                            wave_arrival_time(d,w) = NaN;
                            continue
                        end
                        wave_arrival_time(d,w) = tx_up(locs(1));
                    end

                    gof_save(d) = gof;
                    fitted_curve_sv(d).fit = fitted_curve;
                    conf = confint(fitted_curve);
                    conf_save(d,:) = any(isnan(conf)); % tells us which parameters hit their limits


                    mdl = fitlm(wave_arrival_time(d,1:Width),app.Data.wx);
                    SWS(d) = mdl.Coefficients{2,1};
                    Rsq(d) = mdl.Rsquared.Ordinary;


                end


                app.Data.WAT{tp} = wave_arrival_time;

                app.Data.SWS{tp} = SWS;
                app.Data.Rsq{tp} = Rsq; % Rsquared confidence in the linear fit
%                 app.Data.mdl{tp} = mdl;
% 
%                 app.Data.gof{tp} = gof_save;
%                 app.Data.curve_fit{tp} = fitted_curve_sv;
%                 app.Data.conf_save{tp} = conf_save;

                %                 app.ProgressTextArea.Value{1} = sprintf('Taps completed: %i/%i',t_ind,nTaps);
                t_ind = t_ind + 1;
            end
            close(fw)
        end

        function calculateRadonTransform(app)


            [Depth,Width,Frames,~] = size(app.Data.raw);
            dt = 1/(app.Params.Ffast)*10^3 ;
            tx = 0:dt :(Frames-1) * dt; %ms
            fr_ms = 1/dt*1.5; % number of frames per 1.5 millisecond
            fw = waitbar(0,'Fitting curves...');

            wx =  app.Data.wx ;%0:app.US.Trans.spacingMm:(Width-1)*app.US.Trans.spacingMm;


            x_start = 1;
            x_end = Width;

            t_start = 1;
            t_tot = Frames;

            nTaps = length(app.Params.tap_look);
            t_ind = 1;
            for tp = app.Params.tap_look
                %                 wave_arrival_time = nan(Depth,Width);

                displ_dep = app.Data.filt_fft(:,:,:,tp);

                waitbar((t_ind-1)/(nTaps),fw,'Fitting curves...');
                for dp = app.Params.I_dep_look

                    %                     waitbar(((t_ind-1)*Depth+dp)/(Depth*nTaps),fw,'Fitting curves...');


                    S = zeros(Frames,Frames);
                    Iraw =fliplr(squeeze(displ_dep(dp,:,:))); % this is flipped because the propagation is always moving "away" 20mm > 0mm instead of the opposite

                    I = Iraw/max(Iraw,[],'all'); % normalise to between 0 and 1
                    %
                    %
                    %         figure(1); subplot(1,3,1);
                    %         imshow(I,'Xdata',tx,'Ydata',wx,'InitialMagnification','fit');
                    %         hold on;
                    %         colormap(gca,hot), colorbar
                    %         shading interp

                    %         xx = 0.1;
                    x = x_start:x_end;
                    nX = length(x);
                    for t_start = 1:t_tot-1-fr_ms
                        for t_end = t_start+1:t_start+1+fr_ms

                            ct = (x_end - x_start)/(t_end-t_start);

                            t2 = round((x - x_start)/ct + t_start);

                            %                             sum_vals = zeros(nX,1);
                            iS = sub2ind(size(I),x,t2); % get values on the diagonal formed by x, t2
                            %                             for iX = 1:nX
                            %                                 sum_vals(iX) = I(x(iX),t2(iX));
                            %
                            %                             end
                            S(t_start,t_end) = sum(I(iS));
                        end

                    end

                    [~,mI] = max(S,[],'all','linear');
                    [ts,te] = ind2sub(size(S),mI);

                    SWS_temp = diff(wx([x_end,x_start])) / diff(tx([te,ts]));
                    if SWS_temp > 200 % not physiologically reasonable so don't try to make it more accurate
                        SWS(dp) = NaN;
                        continue
                    end


                    %         figure(1); subplot(1,3,2);imagesc(S)
                    %
                    %         hold on;
                    %         plot(te,ts,'kx');
                    %         hold off;
                    %         drawnow
                    %

                    % Now that we have a general low resolution solution
                    % for ts and te, we can upsample between them to get a
                    % better resolution value

                    % upsample the width
                    wx_up = wx(1):0.05:wx(end); %0.05 mm res

                    % upsample the time, starting slightly outside the
                    % bounds, in index values (i.e not two seconds, but two
                    % values)
                    if ts < 3
                        tT1 = 1;
                    else
                        tT1 = ts-2;
                    end
                    if te+2 > t_tot
                        tT2 = t_tot;
                    else
                        tT2 = te+2;
                    end
                    tx_up = tx(tT1):1/200:tx(tT2); % 0.005 ms

                    F = griddedInterpolant({wx,tx},I);

                    I_up = F({wx_up,tx_up});

                    nT = length(tx_up);
                    nW = length(wx_up);
                    x = 1:nW;
                    S_int = [];
                    for t_start = 1:nT-1
                        for t_end = t_start+1:nT

                            ct = (nW - 1)/(t_end-t_start);

                            t2 = round((x - 1)/ct + t_start);

                            iS = sub2ind(size(I_up),x,t2); % get values on the diagonal formed by x, t2

                            S_int(t_start,t_end) = sum(I_up(iS));

                        end

                    end

                    [val,mI] = max(S_int,[],'all','linear');
                    [ts,te] = ind2sub(size(S_int),mI);
                    SWS(dp) = diff(wx_up([nW,1])) / diff(tx_up([te,ts]));
                    Rsq(dp) = val;

                end
%                 app.ProgressTextArea.Value{1} = sprintf('Taps completed: %i/%i',t_ind,nTaps);
                t_ind = t_ind + 1;

                app.Data.SWS{tp} = -SWS;
                app.Data.Rsq{tp} = Rsq;
            end

            close(fw)
        end

        function plotBmode(app)
            cla(app.imAxes,'reset')

            tp = app.Plot.currentTap;
            dp = app.Plot.I_currentDepth;

            imagesc(app.imAxes,app.Data.wx,app.Data.dx, app.Data.Bmode(:,:,tp));

            colormap(app.imAxes,"gray")
            hold(app.imAxes,'on');

            d_ind = 1;
            % plot the lines of interest
            for d = app.Params.I_dep_look


                hp = plot(app.imAxes,app.Data.wx([1,end]),app.Data.dx([d d]),'LineStyle',...
                    '-','LineWidth',1.5,'color',app.Plot.cmap(d_ind,:));
                if d == dp
                    hp.LineWidth = 3;
                    hp.LineStyle = ':';
                    %                     d_ind_cur = d_ind;
                end
                d_ind = d_ind+1;
                
            end
            view(app.imAxes,[0 90])
            app.imAxes.Position(3) = 300;
            axis(app.imAxes,'tight')
            xlabel(app.imAxes,'Width (mm)')
            ylabel(app.imAxes,'Depth (mm)')
            %              axis(app.imAxes,'ti')
            hold(app.imAxes,'off');

            if app.Plot.calcDisp == 1
                if app.SingleLineCheckBox.Value == 0 % All lines plotted on tap
                    ind_depth = app.Params.I_dep_look;
                else % only one line on tap
                    ind_depth = dp;
                end

                dt = 1/(app.Params.Ffast)*10^3 ;
                tx = 0:dt :(app.Data.nAcq-7) * dt; %ms
                imagesc(app.waveWidthAxes,tx,app.Data.wx  ,squeeze(app.Data.filt_fft(dp,:,:,tp)));
                if ~strcmp(app.MethodDropDown.Value,'Radon')
                hold(app.waveWidthAxes,'on')
                plot(app.waveWidthAxes,app.Data.WAT{tp}(dp,:),app.Data.wx,'x')
                hold(app.waveWidthAxes,'off')
                end
                %                 colormap(app.waveWidthAxes,'gray')
                axis(app.waveWidthAxes,'tight')
                caxis(app.waveWidthAxes,[-0.05 0.05])
                colormap(app.waveWidthAxes,gray(256))
                xlabel(app.waveWidthAxes,'Time after tap (ms)')
                ylabel(app.waveWidthAxes,'Width (mm)')

                hp = plot(app.overTapAxes,tp,(-app.Data.SWS{tp}(dp)),'o');
                hp.MarkerFaceColor = [1 0 0];
                hp.MarkerSize = 10.2;
                hold(app.overTapAxes,'on')
                %   plot over the taps
                for t = app.Params.tap_look
%                     if ~strcmp(app.MethodDropDown.Value,'Radon')
%                         mark_size  = [app.Data.gof{t}.rsquare]'*10;
%                         mark_size(mark_size<0.5) = 0.5;
%                     else
%                         mark_size = 10;
%                     end
                    

                    hp = plot(app.overTapAxes,t,(-app.Data.SWS{t}(ind_depth)),'o');
                    iD = ind_depth == app.Params.I_dep_look;
                    set(hp,{'Markeredgecolor'},num2cell(app.Plot.cmap(iD,:),2),{'markerfacecolor'},...
                        num2cell(app.Plot.cmap(iD,:),2));%,{'markersize'},num2cell([app.Data.gof{t}.rsquare]'*10,2))

                end
                hold(app.overTapAxes,'off')

                xlabel(app.overTapAxes,'Tap')
                ylabel(app.overTapAxes,'SWS (m/s)')
            end

        end


        function getDepths(app)

            nD = app.NumLinesSpinner.Value;
            d1 = app.Params.I_depth_lims(1); 
            d2 = app.Params.I_depth_lims(2);
            if d2 >= app.Data.Depth - 6
                d2 = app.Data.Depth - 6; % to account for displacement averaging along the depth
            end
            app.Params.I_dep_look = round(linspace(d1,d2,nD));
            app.Plot.cmap = viridis(nD);

            d_diff = diff(app.Data.dx(app.Params.I_dep_look(1:2)));
            app.CurrentDepthSpinner.Step = round(d_diff,2);
            app.CurrentDepthSpinner.Value = app.Data.dx(app.Params.I_dep_look(1));
        end

        function getTaps(app)

            nT = app.TapSpinner.Value;
            app.Params.tap_look = round(linspace(1,app.Data.nTaps,nT));

            t_diff = diff(app.Params.tap_look(1:2))+1;
            app.CurrentTapSpinner.Step = t_diff;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, IData, QData, Fprobe, Ffast, Fslow, wx)
            [Depth,Width,nAcq,nTaps] = size(IData);

            app.Data.Depth = Depth;
            app.Data.Width = Width;
            app.Data.nAcq = nAcq;
            app.Data.nTaps = nTaps;
            app.Data.Bmode = log(sqrt(squeeze(IData(:,:,1,:)).^2 + squeeze(QData(:,:,1,:)).^2));
            app.Data.I = IData;
            app.Data.Q = QData;
            app.Data.wx = wx; % the width positions
            app.Data.dx = 0:1/Fprobe:1/Fprobe*(Depth-1);

            app.Params.Fprobe = Fprobe; % Centre frequency of the probe
            app.Params.Fslow = Fslow; % SLOW frame sample rate
            app.Params.Ffast = Ffast; % FAST frame sample rate

            app.NumLinesSpinner.Value = 6;
            app.NumLinesSpinner.Limits = [1,Depth-6]; % note that this is - 6
            %             because we assume an M value of 5 in Loupas Displacement and
            %             we will run out of depths

            depth_lims =  app.NumLinesSpinner.Limits;
            app.CurrentDepthSpinner.ValueDisplayFormat = '%0.2f';

            app.Params.I_depth_lims = sort(depth_lims); % the I signifies the depth INDEX. Not mm measurement
            app.Plot.I_currentDepth = 1;
            app.CurrentDepthSpinner.Value =  app.Data.dx(app.Plot.I_currentDepth);
            app.CurrentDepthSpinner.Limits = app.Data.dx(app.NumLinesSpinner.Limits);
            getDepths(app);

            app.TapSpinner.Value = 10;
            if nTaps == 1
                app.TapSpinner.Limits = [1 2];
            else
                app.TapSpinner.Limits = [1 nTaps];
            end
            app.Plot.currentTap = 1;
            app.CurrentTapSpinner.Value =  app.Plot.currentTap;
            app.CurrentTapSpinner.Limits = app.TapSpinner.Limits;
            getTaps(app)

            app.Plot.calcDisp = 0; % displacement has not been calculated

            plotBmode(app);


        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)


            LoupasDisplacement(app)

            if strcmp(app.MethodDropDown.Value,'Radon')
                calculateRadonTransform(app)
            else
                calculateSurfacePeaks(app)
            end

            app.Plot.calcDisp = 1;
            % plot the wave

            plotBmode(app)


        end

        % Button pushed function: ROIDepthButton
        function ROIDepthButtonPushed(app, event)
            app.ROIDepthButton.BackgroundColor = [0 0.5 0.7];
            % get the depth values from the line
            hline = drawline(app.imAxes);

            % once the depths have been established, get the depths to look
            % at

            depth_lims = hline.Position(:,2); % the top and bottom of the line
            d1 = find(depth_lims(1)<app.Data.dx,1,'first');
            d2 = find(depth_lims(2)<app.Data.dx,1,'first');
            app.Params.I_depth_lims = sort([d1,d2]);

            getDepths(app)
            plotBmode(app)
            app.ROIDepthButton.BackgroundColor = [0.96,0.96,0.96];
        end

        % Value changed function: NumLinesSpinner
        function NumLinesSpinnerValueChanged(app, event)

            %             update the depth values and update the plot
            getDepths(app)
            plotBmode(app)
        end

        % Value changed function: TapSpinner
        function TapSpinnerValueChanged(app, event)

            getTaps(app)
            plotBmode(app)
        end

        % Value changed function: CurrentDepthSpinner
        function CurrentDepthSpinnerValueChanged(app, event)
            value = app.CurrentDepthSpinner.Value;
            Id = find(value < app.Data.dx,1, "first");

            [~,I] = min(abs(app.Params.I_dep_look-Id));
            app.Plot.I_currentDepth = app.Params.I_dep_look(I);
            app.CurrentDepthSpinner.Value = app.Data.dx(app.Plot.I_currentDepth);
            plotBmode(app)
        end

        % Value changed function: CurrentTapSpinner
        function CurrentTapSpinnerValueChanged(app, event)
            value = app.CurrentTapSpinner.Value;
            % the values in the spinner are slightly different than the
            % selected taps because you have to specify a "step" in the
            % spinner values, but it's not necessarily the same step between taps
            [~,I] = min(abs(app.Params.tap_look-value));
            app.Plot.currentTap = app.Params.tap_look(I);
            app.CurrentTapSpinner.Value = app.Plot.currentTap;

            plotBmode(app)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;

            switch key
                case 'rightarrow' % move between taps
                    value = app.CurrentTapSpinner.Value;
                    % the values in the spinner are slightly different than the
                    % selected taps because you have to specify a "step" in the
                    % spinner values, but it's not necessarily the same step between taps
                    [~,I] = min(abs(app.Params.tap_look-value));
                    if I == app.TapSpinner.Value
                        Inew = app.TapSpinner.Value;
                    else
                        Inew = I+1;
                    end
                    app.Plot.currentTap = app.Params.tap_look(Inew);
                    app.CurrentTapSpinner.Value = app.Plot.currentTap;

                    plotBmode(app)
                case 'leftarrow'
                    value = app.CurrentTapSpinner.Value;
                    % the values in the spinner are slightly different than the
                    % selected taps because you have to specify a "step" in the
                    % spinner values, but it's not necessarily the same step between taps
                    [~,I] = min(abs(app.Params.tap_look-value));
                    if I == 1
                        Inew = 1;
                    else
                        Inew = I-1;
                    end
                    app.Plot.currentTap = app.Params.tap_look(Inew);
                    app.CurrentTapSpinner.Value = app.Plot.currentTap;

                    plotBmode(app)
                case 'downarrow'
                    value = app.CurrentDepthSpinner.Value;
                    % the values in the spinner are slightly different than the
                    % selected depths because you have to specify a "step" in the
                    % spinner, but it's not necessarily the same step between
                    % depths

                     Id = find(value < app.Data.dx,1, "first");

                     [~,I] = min(abs(app.Params.I_dep_look-Id));
           
                    if I == app.NumLinesSpinner.Value
                        Inew = app.NumLinesSpinner.Value;
                    else
                        Inew = I+1;
                    end
                    app.Plot.I_currentDepth = app.Params.I_dep_look(Inew);
                    app.CurrentDepthSpinner.Value = app.Data.dx(app.Plot.I_currentDepth);
                    plotBmode(app)
                case 'uparrow'
                    value = app.CurrentDepthSpinner.Value;
                    % the values in the spinner are slightly different than the
                    % selected depths because you have to specify a "step" in the
                    % spinner, but it's not necessarily the same step between
                    % depths
                     Id = find(value < app.Data.dx,1, "first");

                     [~,I] = min(abs(app.Params.I_dep_look-Id));
                    if I == 1
                        Inew = 1;
                    else
                        Inew = I-1;
                    end
                    app.Plot.I_currentDepth = app.Params.I_dep_look(Inew);
                    app.CurrentDepthSpinner.Value = app.Data.dx(app.Plot.I_currentDepth);
                    plotBmode(app)


            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.imAxes = uiaxes(app.UIFigure);
            title(app.imAxes, 'Title')
            xlabel(app.imAxes, 'X')
            ylabel(app.imAxes, 'Y')
            app.waveWidthAxes = uiaxes(app.UIFigure);
            title(app.waveWidthAxes, 'Title')
            xlabel(app.waveWidthAxes, 'X')
            ylabel(app.waveWidthAxes, 'Y')
            zlabel(app.waveWidthAxes, 'Z')
            app.overTapAxes = uiaxes(app.UIFigure);
            title(app.overTapAxes, 'Title')
            xlabel(app.overTapAxes, 'X')
            ylabel(app.overTapAxes, 'Y')
            zlabel(app.overTapAxes, 'Z')
            app.HowmanydepthlinesSpinnerLabel = uilabel(app.UIFigure);
            app.HowmanydepthlinesSpinnerLabel.Text = 'How many depth lines?';
            app.NumLinesSpinner = uispinner(app.UIFigure);
            app.NumLinesSpinner.ValueChangedFcn = createCallbackFcn(app, @NumLinesSpinnerValueChanged, true);
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.HowmanytapsSpinnerLabel = uilabel(app.UIFigure);
            app.HowmanytapsSpinnerLabel.Text = 'How many taps?';
            app.TapSpinner = uispinner(app.UIFigure);
            app.TapSpinner.ValueChangedFcn = createCallbackFcn(app, @TapSpinnerValueChanged, true);
            app.ROIDepthButton = uibutton(app.UIFigure, 'push');
            app.ROIDepthButton.ButtonPushedFcn = createCallbackFcn(app, @ROIDepthButtonPushed, true);
            app.ROIDepthButton.Text = 'Select depth of ROI';
            app.CurrentDepthSpinnerLabel = uilabel(app.UIFigure);
            app.CurrentDepthSpinnerLabel.Text = 'Current Depth';
            app.CurrentDepthSpinner = uispinner(app.UIFigure);
            app.CurrentDepthSpinner.ValueChangedFcn = createCallbackFcn(app, @CurrentDepthSpinnerValueChanged, true);
            app.CurrentTapSpinnerLabel = uilabel(app.UIFigure);
            app.CurrentTapSpinnerLabel.Text = 'Current Tap';
            app.CurrentTapSpinner = uispinner(app.UIFigure);
            app.CurrentTapSpinner.RoundFractionalValues = 'on';
            app.CurrentTapSpinner.ValueChangedFcn = createCallbackFcn(app, @CurrentTapSpinnerValueChanged, true);
            app.WhichmethodDropDownLabel = uilabel(app.UIFigure);
            app.WhichmethodDropDownLabel.Text = 'Which method?';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'Surface Fit', 'Radon'};
            app.MethodDropDown.Value = 'Radon';
            app.SingleLineCheckBox = uicheckbox(app.UIFigure);
            app.SingleLineCheckBox.Text = 'Single line';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tapCheck_change(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end