classdef calculateDisplacement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UseparallelcomputingtoolboxfasterLabel  matlab.ui.control.Label
        ParallelComputeCheckBox        matlab.ui.control.CheckBox
        SelectROICheckBox              matlab.ui.control.CheckBox
        SelectROIforSWSprocessingoptionalLabel  matlab.ui.control.Label
        CalculateDisplacementCheckBox  matlab.ui.control.CheckBox
        MethodDropDown                 matlab.ui.control.DropDown
        MethodDropDownLabel            matlab.ui.control.Label
        AlltapsCheckBox                matlab.ui.control.CheckBox
        tap2Field                      matlab.ui.control.NumericEditField
        toEditFieldLabel               matlab.ui.control.Label
        tap1Field                      matlab.ui.control.NumericEditField
        ProcessfromtapEditFieldLabel   matlab.ui.control.Label
        CalculatePeaksCheckBox         matlab.ui.control.CheckBox
        ProgressTextArea               matlab.ui.control.TextArea
        ProgressTextAreaLabel          matlab.ui.control.Label
        FcEditField                    matlab.ui.control.NumericEditField
        CutofffrequencyHzEditFieldLabel  matlab.ui.control.Label
        CalculateButton                matlab.ui.control.Button
        FolderselectedEditField        matlab.ui.control.EditField
        FolderselectedEditFieldLabel   matlab.ui.control.Label
        FolderSelectButton             matlab.ui.control.Button
        FilesListBox                   matlab.ui.control.ListBox
        SelectFilestoProcessListBoxLabel  matlab.ui.control.Label
        TimeSpinner                    matlab.ui.control.Spinner
        NumberofTimeSamplesSpinnerLabel  matlab.ui.control.Label
        DepthSpinner                   matlab.ui.control.Spinner
        NumberofDepthSamplesSpinnerLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        files % Description
        US % ultrasound data
        displ % displacement data
        flags % flags to indicate conditions
    end
    
    methods (Access = private)
        
        function app = LoupasDisplacement(app)
            
            
            M = app.DepthSpinner.Value; % number of depth pixels to use in displacement calculation
            N = app.TimeSpinner.Value; % number of time (frame) values to use in displacement calculation
            
            f_cutoff = app.FcEditField.Value; % low pass filter cut-off frequency
            
            [Depth,Width,nAcq,nTaps] = size(app.US.I); 
                        
            c_sound = app.US.Resource.Parameters.speedOfSound;
            fc = app.US.Trans.frequency*10^6; % centre frequency of probe in Hz
            Fs = app.US.CollectionParams.fastFrameRate; % between frame imaging rate

            % initialize the displacement matrices
            displ_raw = nan(Depth-M-1,Width,nAcq-N-1,nTaps); % unfiltered/raw
            displ_filt = nan(Depth-M-1,Width,nAcq-N-1,nTaps); % low pass filtered over time
            
            waitbar(0,app.files.status.h_waitbar,'Calculating pixel displacement...')

            No=length(app.displ.tapLims);
            for ii = app.displ.tapLims % for each tap
               
                waitbar(ii/No,app.files.status.h_waitbar,'Calculating pixel displacement...')
                %  Slice the matrices to parallelize efficiently
                I = app.US.I(:,:,:,ii); % get the ii th tap in a smaller matrix
                Q = app.US.Q(:,:,:,ii);

                if app.ParallelComputeCheckBox.Value == 1

                    Nfrs = nAcq - N-1 ;

                    parfor d = 1: Depth-M-1 %all depth samples

                        for fr = 1:Nfrs % along the time axis, in terms of frames
                            m = d:d+M;
                            n = fr:fr+N;

                            num1  =  Q(m,:,n)  .* I(m,:,n+1)  - I(m,:,n)  .* Q(m,:,n+1);
                            den1  =  I(m,:,n)  .* I(m,:,n+1)  + Q(m,:,n)  .* Q(m,:,n+1);
                            num2  =  Q(m,:,n)  .* I(m+1,:,n)  - I(m,:,n)  .* Q(m+1,:,n);
                            den2  =  I(m,:,n)  .* I(m+1,:,n)  + Q(m,:,n)  .* Q(m+1,:,n);

                            toptan = atan2( sum(sum(num1,1),3), sum(sum(den1,1),3));
                            bottan = atan2( sum(sum(num2,1),3), sum(sum(den2,1),3));

                            displ_raw(d,:,fr,ii) = c_sound / (4*pi()*fc) *  toptan  ./  (1+bottan/(2*pi()));


                        end

                    end

                else % don't use parallel computing toolbox

                    for d = 1: Depth-M-1 %all depth samples

                        for fr = 1:nAcq - N-1 % along the time axis, in terms of frames
                            m = d:d+M;
                            n = fr:fr+N;

                            num1  =  Q(m,:,n)  .* I(m,:,n+1)  - I(m,:,n)  .* Q(m,:,n+1);
                            den1  =  I(m,:,n)  .* I(m,:,n+1)  + Q(m,:,n)  .* Q(m,:,n+1);
                            num2  =  Q(m,:,n)  .* I(m+1,:,n)  - I(m,:,n)  .* Q(m+1,:,n);
                            den2  =  I(m,:,n)  .* I(m+1,:,n)  + Q(m,:,n)  .* Q(m+1,:,n);

                            toptan = atan2( sum(sum(num1,1),3), sum(sum(den1,1),3));
                            bottan = atan2( sum(sum(num2,1),3), sum(sum(den2,1),3));

                            displ_raw(d,:,fr,ii) = c_sound / (4*pi()*fc) *  toptan  ./  (1+bottan/(2*pi()));


                        end

                    end
                end
                for w = 1:Width
                    
                    % Low pass filter the displacement
                    displ_filt(:,w,:,ii)  = detrend(LowPassButterworth(squeeze(displ_raw(:,w,:,ii)),4,f_cutoff,Fs)')';
                    
                end
                
            end
            
            app.displ.raw = displ_raw;
            app.displ.filt = displ_filt;
                
        
            function x_out = LowPassButterworth(X,n,fc,fsample)

                [r,c]=size(X);
                npad = 15;

                % filter along columns.
                npts = c;
                nrows = r;

                f = fsample/2;
                Wn = fc/f;
                ftype = 'low';

                % Transfer Function design
                [b,a] = butter(n,Wn,ftype);
                x_out = nan(nrows,npts);
                % for each row, filter it after padding the ends to account for end effects
                for i = 1:nrows
                    nanind = isnan(X(i,:));
                    ind_nums = find(~nanind);
                    nptfilt = length(ind_nums);

                    x = X(i,ind_nums);
                    n = length(x);
                    t1 = [1:1:npad]/fsample;
                    t = [npad+1:npad+n]/fsample;
                    t2 = [npad+n+1:npad*2+n+1]/fsample;

                    X_new(i,:) = interp1(t, x, [t1,t,t2], 'linear', 'extrap');


                end

                x = filtfilt(b,a,X_new')';

                x = x(:,npad+1:npad+n);

                x_out(:,ind_nums) = x;
    
            end

        
        end


        function app = filterDisplacement(app)
            
            
            [Depth,Width,Time,~] = size(app.displ.raw);
            
           
            pf = 10; % padding factor

            % set up the filter
            filtt = true(Width*pf,Time*pf);

            filtt(Width*pf/2:end,1:Time*pf/2) = 0;
            filtt(1:Width*pf/2,Time*pf/2:end) = 0;
            filtt(1:end,1)  = 0;
            filtt(1:end,end) = 0;
            filtt(1,1:end) = 0;
            filtt(end,1:end) = 0;

            No=length(app.displ.tapLims);
            for tp = app.displ.tapLims
                waitbar(tp/No,app.files.status.h_waitbar,'Spatially filtering pixel displacement...')
                        
                fft_tap = app.displ.filt(:,:,:,tp);
                filt_save = nan(Depth,Width,Time);

                if app.ParallelComputeCheckBox.Value == 1
                    parfor d = 1:Depth
                        fft_var = squeeze(fft_tap(d,:,:));

                        Y = (fft2(fft_var,Width*pf,Time*pf));

                        Y_filt = filtt.*Y;

                        fft_filt = ifft2(Y_filt,Width*pf,Time*pf);
                        filt_save(d,:,:) = real(fft_filt(1:Width,1:Time));
                    end
                else
                    for d = 1:Depth
                        fft_var = squeeze(fft_tap(d,:,:));

                        Y = (fft2(fft_var,Width*pf,Time*pf));

                        Y_filt = filtt.*Y;

                        fft_filt = ifft2(Y_filt,Width*pf,Time*pf);
                        filt_save(d,:,:) = real(fft_filt(1:Width,1:Time));

                    end
                end
                app.displ.filt_fft(:,:,:,tp) = filt_save;
            end
        end
        
        function app = getROI(app)

            [Depth,Width,Frames,~] = size(app.displ.raw);
            wlmm = 1540/app.US.Trans.frequency * 10^-3;
            wx = 0:app.US.Trans.spacingMm:(Width-1)*app.US.Trans.spacingMm;
            
            wxRF = wx(1:2:end); % every second element in transducer is currently being used for RF; may change in future
            
            % NOTE: there is 2x the number of samples; Verasonics states that:
            % size of each acquisition segment in samples is given by:
            % 2*(Receive.endDepth – Receive.startDepth)*Resource.RcvBuffer.samplesPerWave
            rf_divs = wlmm/app.US.Receive(1).samplesPerWave/2;
            dxRF = app.US.Receive(1).startDepth*wlmm:rf_divs:(app.US.Receive(1).endDepth)*wlmm-rf_divs; % The RF data is deeper
            dx = app.US.P.startDepth*wlmm:wlmm*app.US.PData.PDelta(3):(app.US.P.endDepth)*wlmm; % NOTE: it's divided by 2 because PData.Pdelta(3) = 0.5, meaning two samples per wavelength
            
            wx_up = wxRF(1):diff(wxRF(1:2))/4:wxRF(end); % upsample the width by 4
            dx_up = dxRF(1):diff(dxRF(1:2))/8:dxRF(end); % upsample the depth by 8
            [~,~,a,b] = size(app.US.RF); % depending on when the data was collected, it could be a tap or a frame
            for t = 1
                if a > 1 && b > 1 % historical, when all taps were included
                    rf_env(:,:,t) = envelope(double(app.US.RF(:,:,1,t)),25,'analytic');
                else
                    rf_env(:,:,t) = envelope(double(app.US.RF(:,:,t,1)),25,'analytic');
                end
                F = griddedInterpolant({dxRF,wxRF},rf_env(:,:,t)); % set up the interpolant
                rf_im = F({dx_up,wx_up}); % interpolate over the additional points
                rf_im = medfilt2(rf_im,[5,3]);
                
                imlog = log(1+abs(rf_im));
                
                bmode = imlog;
            end
            
            hf = figure;
            hf.Position(3:4) = [560.0000  180.000]; % to get the scale right
            ax = axes(hf);
            imagesc(ax,wx_up,dx_up, bmode)
             caxis(ax,[3,max( bmode,[],'all')])
             colormap(ax,gray(256)); ylim(ax,dx([1,end]))
            roi = drawrectangle(ax);
            app.US.wxLims = [min(roi.Vertices(:,1)) max(roi.Vertices(:,1))];
            app.US.dxLims = [min(roi.Vertices(:,2)) max(roi.Vertices(:,2))];
% find the indices
            app.US.wLims = find(app.US.wxLims(1) > wx,1,'last'):find(app.US.wxLims(2) > wx, 1, 'last');
            app.US.dLims = find(app.US.dxLims(1) > dx,1,'last'):find(app.US.dxLims(2) > dx, 1, 'last');
            % Verifying we do not go outside the bounds of the image
            app.US.dLims(app.US.dLims > Depth | app.US.dLims < 1) = [];
            app.US.wLims(app.US.wLims > Width | app.US.wLims < 1) = [];
        end
        
        
        function calculatePeaks(app)
            
            [Depth,Width,Frames,~] = size(app.displ.raw);
            dt = 1/(app.US.CollectionParams.fastFrameRate) * 1000;
            tx = 0:dt :(Frames-1) * dt; %ms
            
            fw = waitbar(0,'Fitting curves...');
            
            wx = 0:app.US.Trans.spacingMm:(Width-1)*app.US.Trans.spacingMm;
            t_ind = 1;
            rng = 5;
            
            % for the curve fit to figure out where the first peak is.
            % of the gaussian pulse
            pulse_centre = 1.5;
            pulse_width = 0.5;
            
            % of the sine
            pk_mag = 0.05;
            pk_width = 7.5;
            pk_offset = 1;
            % initial conditions (tuned by LW)
            x0 = [pulse_centre, pulse_width, pk_mag, pk_width, pk_offset]; % pulse centre, width of pulse, magnitude of peaks, pk to pk width of sine
            x0_lower = [0.5,0.3,pk_mag,0.5,-5];
            x0_upper = [2,0.8,pk_mag,8,10];
            % gaussian pulse mixed with a sine wave
            pulse_func = @(a,b,c,d,e,x) exp(-(x-a).^2/(2*b.^2)).*c .* sin(d*x - e);
            pulse_s = fittype(pulse_func );
            
            nTaps = range(app.displ.tapLims);
            for tp = app.displ.tapLims
                wave_arrival_time = nan(Depth,Width); % initialise the wave arrival time
                SWS = nan(Depth,1); % initialise the SWS for each depth line
                Rsq = nan(Depth,1); % initialise the confidence value for each depth line


                for d = app.US.dLims % for each of the depth lines to calculate the SWS
                    
                    waitbar(((t_ind-1)*Depth+d)/(Depth*nTaps),fw,'Fitting curves...');
                    % get an estimate of where the peak values are
                    % starting with more uniform data
                    displ_dep = squeeze(app.displ.filt_fft(d,:,:,tp));
                    
                    envv = medfilt2(envelope(displ_dep',25,"analytic")',[15 3]);
                    [~,Ipk] = max(envv,[],2); % get the peak of the envelope to centre the curve fit
                    
                    disp_env = medfilt2(displ_dep,[15,3]);
                    
                    w_vals = round(Width* [0.1:0.05:0.9]); % at various widths
                    w_vals_save = [];
                    
                    
                    II = 1;
                    Ix_lims_crop = [];
                    
                    
                    for w = w_vals
                        
                        pulse_centre = tx(Ipk(w));
                        x0(1) = pulse_centre;
                        
                        disp_sq = disp_env(w,:)'; % the single line
                        rdd = range(disp_sq); % tune the initial guess to the range of the data
                        x0(3) = rdd*10^6/2;
                        x0_lower(3) = x0(3)*2/3;
                        x0_upper(3) = x0(3)*2;
                        % fit the curve to the wave; note that we have to
                        % multiply the displacement to get function changes
                        % that are meaningful to the optimizer
                        [fitted_curve,~] = fit(tx',disp_sq*10^6,pulse_s,'StartPoint',x0,'tolfun',10e-7,'Lower',x0_lower,'Upper',x0_upper);
                        fit_vals = fitted_curve(tx);
                        
                        [~,Imax] = findpeaks(fit_vals,'NPeaks',1,"MinPeakWidth",4,'MinPeakHeight', x0(3)/10);
                        %
                        %                         figure(10); plot(tx,disp_sq*10^6); hold on; plot(tx,fit_vals)
                        %                         plot(tx(Imax),disp_sq(Imax)*10^6,'o'); hold off;
                        if isempty(Imax)
                            continue
                        end


                        if Imax + rng >= Frames
                            Ix_lims_crop(II,1:2) = [Imax-rng*2,Frames];
                        elseif Imax -rng <= 0
                            Ix_lims_crop(II,1:2) = [1,Imax+rng*2];
                        else
                            Ix_lims_crop(II,1:2) = [Imax-rng,Imax+rng];
                        end
                        w_vals_save(1,II) = w;
                        II = II + 1;
                    end
                    
                    w_vals_save(1:end+2) = [1, w_vals_save,Width] ;
                    Ix_lims_crop(2:end+1,:) =Ix_lims_crop;
                    Ix_lims_crop(end+1,:) = Ix_lims_crop(end,:);
                    
                    %                     Ix_lims(:,1) = round(interp1q(w_vals',Ix_lims_crop(:,1),[1:Width]'));
                    %                     Ix_lims(:,2) = round(interp1q(w_vals',Ix_lims_crop(:,2),[1:Width]'));
                    %
                    % set up a linear fit to the upper and lower bounds - gets rid of outliers
                    b1 = [ones(1,length(w_vals_save)),;w_vals_save]'\Ix_lims_crop(:,1); % left bound
                    b2 = [ones(1,length(w_vals_save)),;w_vals_save]'\Ix_lims_crop(:,2); % left bound
                    
                    Ix_lims(:,1) = b1(1) + b1(2) * [1:Width];
                    Ix_lims(:,2) = b2(1) + b2(2) * [1:Width];
                    
                    %                     Ix_lims = floor(Ix_lims);
                    %                     Ix_lims(Ix_lims<1) = 1;
                    %                     Ix_lims(Ix_lims>Width) = Width;
                    
                    %
                    %                     % add the beginning and end elements of width without
                    %                     % measuring; can be noisy
                    %                     w_vals(1:end+2) = [1, w_vals,Width] ;
                    %                     Ix_lims_crop(2:end+1,:) =Ix_lims_crop;
                    %                     Ix_lims_crop(end+1,:) = Ix_lims_crop(end,:);
                    %
                    %                     %                     Ix_lims(:,1) = round(interp1q(w_vals',Ix_lims_crop(:,1),[1:Width]'));
                    %                     %                     Ix_lims(:,2) = round(interp1q(w_vals',Ix_lims_crop(:,2),[1:Width]'));
                    %                     %
                    %                     % set up a linear fit to the upper and lower bounds - gets rid of outliers
                    %                     b1 = [ones(1,length(w_vals)),;w_vals]'\Ix_lims_crop(:,1); % left bound
                    %                     b2 = [ones(1,length(w_vals)),;w_vals]'\Ix_lims_crop(:,2); % left bound
                    %
                    %                     Ix_lims(:,1) = b1(1) + b1(2) * [1:Width];
                    %                     Ix_lims(:,2) = b2(1) + b2(2) * [1:Width];
                    %
                    Ix_lims = floor(Ix_lims);
                    ind1 = Ix_lims(:,1) < 1;
                    Ix_lims(ind1,:) = Ix_lims(ind1,:) - Ix_lims(ind1,1) + 1; % shift the range
                    
                    indbig = Ix_lims(:,2) > Frames;
                    Ix_lims(indbig,:) = Ix_lims(indbig,:) - (Ix_lims(indbig,2) - Frames) ; % shift the range
                    Ix_lims(Ix_lims>Frames) = Frames;
                    
                    
                    %                     figure;hold on;
                    %                     plot(w_vals',tx_lims_crop(:,1),'o')
                    %                     plot([1:Width]',tx_lims(:,1))
                    %                     plot(w_vals',tx_lims_crop(:,2),'o')
                    %                     plot([1:Width]',tx_lims(:,2))
                    %                     tx_lims(:,2) = interp1q(w_vals',tx_lims_crop(:,2),1:Width);
                    %                     tic
                    for w = app.US.wLims% 1:Width
                        
                        disp_sq = displ_dep(w,:)*10^6; % squeezed displacement data, shows time for each width and depth
                        
                        I_fit = Ix_lims(w,1):Ix_lims(w,2);
                        
                        f = fit(tx(I_fit)',disp_sq(I_fit)','poly6'); %fit a 6th order polynomial
                        
                        t_small = linspace(tx(Ix_lims(w,1)),tx(Ix_lims(w,2)),10000); % create a fine grid of time points to numerically evaluate the peak
                        
                        
                        ff = feval(f,t_small); % evaluate the curve fit at these points
                        [~,It] = findpeaks(ff,'NPeaks',1);
                        if isempty(It)
                            wave_arrival_time(d,w) = NaN;
                        else
                            wave_arrival_time(d,w) = t_small(It); % save the time of wave arrival
                        end
                        
                    end
                    %                     toc
                    mdl = fitlm(wave_arrival_time(d,:),wx); % linear fit to the width travelled and the arrival time; slope is SWS in m/s
                    
                    SWS(d) = mdl.Coefficients{2,1};
                    Rsq(d) = mdl.Rsquared.Ordinary;
                end
                
                app.displ.WAT{tp} = wave_arrival_time;
                
                app.displ.SWS{tp} = SWS;
                app.displ.Rsq{tp} = Rsq; % Rsquared confidence in the linear fit
                app.displ.mdl{tp} = mdl;
                
                t_ind = t_ind + 1;
            end
            close(fw)


        end
        
        function calculateRadonTransform(app)
            
            
            [Depth,Width,Frames,~] = size(app.displ.filt_fft);
            dt = 1/(app.US.CollectionParams.fastFrameRate) * 1000;
            tx = 0:dt :(Frames-1) * dt; %ms
            fr_ms = 1/dt*1.5; % number of frames per 1.5 millisecond
            
            waitbar(0,app.files.status.h_waitbar,'Computing radon transform...')
            
            wx = 0:app.US.Trans.spacingMm:(Width-1)*app.US.Trans.spacingMm;
            

            theta1 = 0.1:0.1:20;% range of theta angles in the Radon transform
            
            wx_diff = wx(2)-wx(1);
            tx_diff = tx(2)- tx(1);

          
            nTaps = range(app.displ.tapLims);
            
            t_ind = 1;
            for tp = app.displ.tapLims
                SWS = nan(Depth,1); % initialise the SWS for each depth line
                Rsq = nan(Depth,1); % initialise the confidence value for each depth line
                
                
                displ_dep = app.displ.filt_fft(:,:,:,tp);
                
                waitbar((t_ind-1)/(nTaps),app.files.status.h_waitbar,'Computing radon transform...')
                
                for dp = app.US.dLims % for each depth 
                    
                    Z_filt =fliplr(squeeze(displ_dep(dp,:,:)));
                    
                    % first iteration of Radon transform
                    [R,~] = radon(Z_filt,theta1);
                    [Rmax,I] = max(R,[],'all');
                    [~,Ith] = ind2sub(size(R),I);
                    
                    theta_mid = theta1(Ith);
                    
                    % second iteration of Radon transform
                    theta2 = theta_mid-2:0.01:theta_mid+2; % smaller resolution for higher precision
                    [R,~] = radon(Z_filt,theta2);
                    [Rmax,I] = max(R,[],'all');
                    [~,Ith] = ind2sub(size(R),I);

                    % Now calculate wave speed
                    deltaT=(tand(theta2(Ith)))*(tx_diff); % computed time delay
                    SWS(dp) = wx_diff/deltaT;
                    Rsq(dp) = Rmax;
                    
                end
                
%                 sprintf('Taps completed: %i/%i',t_ind,nTaps);
                t_ind = t_ind + 1;
                
                app.displ.SWS{tp} = SWS;
                app.displ.Rsq{tp} = Rsq;
            end


        end

      
        function calculateSurfacePeaks(app)
            
            [Depth,Width,Frames,~] = size(app.displ.raw);
            dt = 1/(app.US.CollectionParams.fastFrameRate) * 1000;
            tx = 0:dt :(Frames-1) * dt; %ms
            
            fw = waitbar(0,'Fitting curves...');
            
            wx = 0:app.US.Trans.spacingMm:(Width-1)*app.US.Trans.spacingMm;
            
            
            %  x0 = [pulse_centre, pulse_width,pulse_slope,pulse_decay, pk_mag, pk_width,pk_offset]
            surf_func = @(pc,pw,ps,pd,mag,pkw,pko,t,w) mag .* sin(pkw*(t)  - (ps*8.1)*w - pko) .*...
                exp(-((t-(ps*w)-pc).^2)/((2.*pw).^2)).*(-pd*w+1);
            pulse_surf = fittype(surf_func,'independent',{'t','w'}); % time and width; width is normalised from 0 to 1
            
            wn = [1:Width]/Width; % normalized width
            
            [Tx,Wn] = meshgrid(tx,wn);
            
            tx_up = linspace(tx(1),tx(end),5000); % for upsampling
            [Tx_up,Wn_up] = meshgrid(tx_up,wn);
            
            % constant guesses for the gaussian (note pulse = gauss)
            % NONE are constant currently
            
            % constant estimates for the sine waves (note pk = sine)
            pk_width = 8; % pk_width/pi() = approximately how many sine waves are within the pulse
            pk_offset = -4; % shifting of where the sine wave starts within the pulse
            
            t_ind = 1; % to count taps
            
            nTaps = length(app.displ.tapLims);
            for tp = app.displ.tapLims
                wave_arrival_time = nan(Depth,Width);% initialise the wave arrival time
                SWS = nan(Depth,1); % initialise the SWS for each depth line
                Rsq = nan(Depth,1); % initialise the confidence value for each depth line
                
                
                for d = app.US.dLims % for each depth value
                    
                    waitbar(((t_ind-1)*Depth+d)/(Depth*nTaps),fw,'Fitting curves...');
                    displ_dep = squeeze(app.displ.filt_fft(d,:,:,tp));
                    
                    envv = medfilt2(envelope(displ_dep',25,"analytic")',[15 5]);
                    [Vmax,Ipk] = max(envv,[],2); % get the peak of the envelope to centre the curve fit
                    time_centre = tx(Ipk);
                    prc = prctile(envv,50,'all');
                    Ib =sum(imbinarize(envv,prc),2); % basically gives the number of indices above the 50th percentile, gives an idea of the pulse width
                    
                    pulse_width = sqrt(max(Ib)*dt)/2; % an estimation of the gaussian pulse width
                    pulse_centre = time_centre(1); % where the pulse is centred
                    pulse_slope = -range(time_centre); % the approximate time difference between the first and last peak
                    pulse_decay = (1-range(Vmax)/max(Vmax)); % attenuation over the width, currently linear
                    
                    pk_mag = max(Vmax)*10^6; % the sine amplitude > has to be made larger to make the optimizer work
                    
                    % initial surface parameters
                    x0 = [pulse_centre, pulse_width,pulse_slope,pulse_decay, pk_mag, pk_width,pk_offset];
                    x0(1) = pulse_centre;
                    x0(3) = pulse_slope;
                    x0(4) = pulse_decay; % decay
                    x0(5) = pk_mag; % peak magnitude
                    
                    % reasonable bounds for solutions -> lots of tuning
                    % done here
                    x0_lower = [pulse_centre+pulse_slope ,0.05, -2.5,  0,  pk_mag/4,    4, -20];
                    x0_upper = [pulse_centre-pulse_slope ,0.8,  -0.2,   1, pk_mag*3,    12, 0 ];


                    [fitted_curve,gof,output] = fit([Tx(:),Wn(:)],displ_dep(:)*10^6,pulse_surf,'StartPoint',x0,'tolfun',10e-8,'Lower',x0_lower,'Upper',x0_upper);
                    
                    surf_vals = fitted_curve(Tx_up,Wn_up);
                    %                     [pk_max,~] = max(surf_vals,[],2);
                    pk_rng = range(surf_vals');
                    for w = app.US.wLims % for the selected widths
                        [~,locs,~,~] = findpeaks(surf_vals(w,:),'NPeaks',1,'MinPeakProminence',pk_rng(w)/3);%0.01,'MinPeakHeight',pk_max(w)/4);
                        %             figure(10); plot(surf_vals(w,:));hold on; plot(locs,pks,'v');hold off;
                        if length(locs) < 1
                            wave_arrival_time(d,w) = NaN;
                            continue
                        end
                        wave_arrival_time(d,w) = tx_up(locs(1));
                    end
                    
                    gof_save(d) = gof;
                    fitted_curve_sv(d).fit = fitted_curve;
                    conf = confint(fitted_curve);
                    conf_save(d,:) = any(isnan(conf)); % tells us which parameters hit their limits
                    
                    
                    mdl = fitlm(wave_arrival_time(d,1:Width),wx);
                    SWS(d) = mdl.Coefficients{2,1};
                    Rsq(d) = mdl.Rsquared.Ordinary;
                    
                    
                end
                
                
                app.displ.WAT{tp} = wave_arrival_time;
                
                app.displ.SWS{tp} = SWS;
                app.displ.Rsq{tp} = Rsq; % Rsquared confidence in the linear fit
                app.displ.mdl{tp} = mdl;
                
                app.displ.gof{tp} = gof_save;
                app.displ.curve_fit{tp} = fitted_curve_sv;
                app.displ.conf_save{tp} = conf_save;
                
                app.ProgressTextArea.Value{1} = sprintf('Taps completed: %i/%i',t_ind,nTaps);
                t_ind = t_ind + 1;
                %                 IF KEEN TO PLOT
                %                 h = figure('color',[1 1 1]);
                %                 tax = axes(h,'Position',[0.01,0.95,0.1 0.1]);
                %                 text(tax,0,0,sprintf('tap = %i',tp));
                %                 axis off
                %                 subplot(1,5,[1 2])
                %                 imagesc(WAT)
                %                 ylabel('depth')
                %                 xlabel('width')
                %                 title('wave arrival time')
                %
                %                 % figure;
                %                 subplot(1,5,3)
                %                 imagesc(conf_save)
                %                 set(gca,'XTick',[1:9],...
                %                     'Xticklabel',{'pulse_centre', 'pulse_width','pulse_slope','pulse_decay', 'pk_mag', 'pk_width', 'pk_offset', 'pk_slope'},'XticklabelRotation',90);
                %                 title('Limits on parameters')
                %
                %                 subplot(1,5,4)
                %
                %                 plot([gof_save(:).rsquare],1:454)
                %                 set(gca,'Ydir','reverse','Ylim',[0 454])
                %                 title('Goodness of surface fit')
                %
                %                 subplot(1,5,5)
                %                 deps = 1:454;
                %                 ind_g = [gof_save(:).rsquare]>0.4;
                %                 plot(SWS(ind_g),deps(ind_g),'.')
                %                 set(gca,'Ydir','reverse','Ylim',[0 454],'Xdir','reverse')
                %                 title('shear wave speed over depth')
                %
                %                 % plot one set of the fit
                %
                %                                 d = 1;
                %
                %                                 displ_dep = squeeze(app.displ.filt_fft(d,:,:,tp));
                %                                 fitted_curve_sv(d).fit
                % %                                 [Tx,Wx] = meshgrid(tx,wx);
                %                                 figure; surf(Tx,Wn,fitted_curve_sv(d).fit(Tx,Wn),'facealpha',0.5); shading interp;
                %                                 hold on; plot3(Tx(:),Wn(:),displ_dep(:)*10^6,'.')
                
            end
            close(fw)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % check whether they have the appropriate toolboxes
            addOns = matlab.addons.installedAddons;
            find_PCT = strcmp(addOns.Name,"Parallel Computing Toolbox");
            if any(find_PCT) 
                app.flags.parallelComp = 1;
            else % no parallel computing toolbox, don't give the option
                app.flags.parallelComp = 0;
                app.ParallelComputeCheckBox.Enable = "off";
                app.ParallelComputeCheckBox.Value = 0;
            end


        end

        % Button pushed function: FolderSelectButton
        function FolderSelectButtonPushed(app, event)
            dirData = uigetdir("", 'Select Data Folder');
            if dirData == 0
                return
            end
            app.files.dirData = dirData;
            
            app.FolderselectedEditField.Value = dirData; % put the folder name in the top box
            
            filelist = dir([dirData '\**\*.mat']); % find all the mat files within the top level folder
            ind_proc =  contains({filelist(:).name},'displacement.mat') | contains({filelist(:).name},'wave_arrival.mat') | contains({filelist(:).name},'radon.mat'); % don't show the processed files from this GUI
            filelist(ind_proc) = [];
            
            app.FilesListBox.Items = {filelist(:).name}; %put the files in the listbox
            
            app.files.list = {filelist(:).name}; % save the names of the files
            app.files.folders = {filelist(:).folder}; % save the names of the folders
            
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            
            selectedFiles = app.FilesListBox.Value;
            app.ProgressTextArea.Value = {''};
            nfiles = length(selectedFiles);


            app.files.status.h_waitbar = waitbar(0,'Loading file....');
            for f = 1:nfiles
                
                
                iF = find(strcmp(app.files.list,selectedFiles{f})); % get the index of the selected file in the list so that we can also get the folder
                if length(iF)>1
                    errordlg('Multiple files with the same name detected. Please use unique file names. ')
                    return
                end
                app.files.current = app.files.list{iF};
                app.ProgressTextArea.Value{1} = sprintf('Current file: %s',app.files.current);
               
                waitbar(0.05,app.files.status.h_waitbar,'Loading file....');
                app.US = load(fullfile(app.files.folders{iF},app.files.list{iF})); % load the ultrasound file
                waitbar(1,app.files.status.h_waitbar,'File loaded!')
                app.displ.raw = [];
                app.displ.filt = [];
                app.displ.filt_fft = [];
                
                % get the range of taps to process over;
                if app.AlltapsCheckBox.Value == 1
                    app.displ.tapLims = 1:size(app.US.I,4);
                else
                    
                    app.displ.tapLims = app.tap1Field.Value:app.tap2Field.Value;
                end
                i_delete = app.displ.tapLims > size(app.US.I,4);
                app.displ.tapLims(i_delete) = [];
                
                
                switch app.CalculateDisplacementCheckBox.Value
                    case 1 % calculate displacement
                        waitbar(0,app.files.status.h_waitbar,'Calculating pixel displacement...')
                        app = LoupasDisplacement(app);
                        waitbar(1,app.files.status.h_waitbar,'Done!')

                        waitbar(0,app.files.status.h_waitbar,'Spatially filtering pixel displacement...')
                        app = filterDisplacement(app);
                        waitbar(1,app.files.status.h_waitbar,'Done!')

                        displ = app.displ.raw;
                        displ_filt = app.displ.filt;
                        displ_filt_fft = app.displ.filt_fft;
                        Trans = app.US.Trans;


                        waitbar(0.05,app.files.status.h_waitbar,'Saving displacement data...')
                        save(fullfile(app.files.folders{iF},[app.files.list{iF}(1:end-4),'_displacement.mat']),'displ','displ_filt','displ_filt_fft','Trans');
                        waitbar(1,app.files.status.h_waitbar,'Done!')
                        pause(0.5)% allows popup to show completion

                    case 0 % load from file
                        waitbar(0,app.files.status.h_waitbar,'Loading displacement file....')
                        load(fullfile(app.files.folders{iF},[app.files.list{iF}(1:end-4),'_displacement.mat']),'displ','displ_filt','displ_filt_fft')
                        app.displ.raw = displ;
                        app.displ.filt = displ_filt;
                        app.displ.filt_fft = displ_filt_fft;
                        waitbar(1,app.files.status.h_waitbar,'File loaded!')
                        pause(0.5)% allows popup to show completion
                end
                
                
                if app.CalculatePeaksCheckBox.Value == 1
                    [Depth,Width,~,~] = size(app.displ.raw);

                    if app.SelectROICheckBox.Value == 1
                        if f == 1 % this prevents it from popping up a fig every time
                            app = getROI(app);
                        else % the US variable gets overwritten every time
                            app.US.dLims=dLims;
                            app.US.wLims = wLims;
                        end
                    else
                        app.US.dLims = 1:Depth;
                        app.US.wLims = 1:Width;
                    end

                        dLims = app.US.dLims;
                        wLims = app.US.wLims;
                    switch app.MethodDropDown.Value
                        case 'Surface Fit'
                            calculateSurfacePeaks(app);
                            wave_arrival_time = app.displ.WAT;
                            
                            SWS =  app.displ.SWS;
                            Rsq =  app.displ.Rsq ; % Rsquared confidence in the linear fit
                            mdl =  app.displ.mdl;

                            ROI_dx = app.US.dLims;
                            ROI_wx = app.US.wLims;
                            method = 'Surface Fit';
                            
                            gof_save = app.displ.gof;
                            fitted_curve = app.displ.curve_fit;
                            conf_save = app.displ.conf_save;
                            save( fullfile(app.files.folders{iF},[app.files.list{iF}(1:end-4), '_wave_arrival.mat']),...
                                "wave_arrival_time","SWS","Rsq","mdl","gof_save","fitted_curve","conf_save","method","ROI_dx","ROI_wx"); % save the wave arrival time
                            
                        case 'Curve Fit'
                            calculatePeaks(app);
                            wave_arrival_time = app.displ.WAT;
                            
                            SWS =  app.displ.SWS;
                            Rsq =  app.displ.Rsq ; % Rsquared confidence in the linear fit
                            mdl =  app.displ.mdl;
                            
                            ROI_dx = app.US.dLims;
                            ROI_wx = app.US.wLims;
                            method = 'Curve Fit';
                            %             [file,path] = uiputfile('E:\Wisconsin\*.mat');
                            save( fullfile(app.files.folders{iF},[app.files.list{iF}(1:end-4), '_wave_arrival.mat']),"method","wave_arrival_time","SWS","Rsq","mdl","ROI_dx","ROI_wx"); % save the wave arrival time
                        case 'Radon Transform'
                            waitbar(0,app.files.status.h_waitbar,'Computing radon transform...')
                            calculateRadonTransform(app);
                            waitbar(1,app.files.status.h_waitbar,'Done!')
                            
                            SWS =  app.displ.SWS;
                              Rsq =  app.displ.Rsq ; % note that this is a placeholder - it is not actually a measure of Rsq
%                               however, the higher the value here, the
%                               better the assessment of Rsq

                            method = 'Radon Transform';
                            ROI_dx = app.US.dLims;
                            ROI_wx = app.US.wLims;
                            %                             wave_arrival_time = app.displ.WAT;
                            
                            save( fullfile(app.files.folders{iF},[app.files.list{iF}(1:end-4), '_radon.mat']),...
                                "SWS","Rsq","method","ROI_dx","ROI_wx"); % save the wave arrival time
                            
                    end
                    
                end
                timetemp = toc;
                
                app.ProgressTextArea.Value{2} = sprintf('Elapsed time is %0.0f minutes, %0.0f seconds.',...
                    floor(timetemp/60), abs(timetemp/60-floor(timetemp/60))*60);
                app.ProgressTextArea.Value{3} = sprintf('Estimated time remaining: %0.0f minute(s).',ceil(timetemp/60/f*nfiles - timetemp/60));
                app.ProgressTextArea.Value{4} = 'Processed:';
                app.ProgressTextArea.Value{4+f} = app.files.current;
            end

            close(app.files.status.h_waitbar)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.NumberofDepthSamplesSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofDepthSamplesSpinnerLabel.Text = 'Number of Depth Samples';
            app.DepthSpinner = uispinner(app.UIFigure);
            app.DepthSpinner.Value = 5;
            app.NumberofTimeSamplesSpinnerLabel = uilabel(app.UIFigure);
            app.NumberofTimeSamplesSpinnerLabel.Text = 'Number of Time Samples';
            app.TimeSpinner = uispinner(app.UIFigure);
            app.TimeSpinner.Value = 5;
            app.SelectFilestoProcessListBoxLabel = uilabel(app.UIFigure);
            app.SelectFilestoProcessListBoxLabel.Text = 'Select Files to Process:';
            app.FilesListBox = uilistbox(app.UIFigure);
            app.FilesListBox.Items = {};
            app.FilesListBox.Multiselect = 'on';
            app.FilesListBox.Value = {};
            app.FolderSelectButton = uibutton(app.UIFigure, 'push');
            app.FolderSelectButton.ButtonPushedFcn = createCallbackFcn(app, @FolderSelectButtonPushed, true);
            app.FolderSelectButton.Text = 'Folder Select';
            app.FolderselectedEditFieldLabel = uilabel(app.UIFigure);
            app.FolderselectedEditFieldLabel.Text = 'Folder selected:';
            app.FolderselectedEditField = uieditfield(app.UIFigure, 'text');
            app.FolderselectedEditField.Editable = 'off';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.CutofffrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.CutofffrequencyHzEditFieldLabel.Text = 'Cut-off frequency (Hz):';
            app.FcEditField = uieditfield(app.UIFigure, 'numeric');
            app.FcEditField.Limits = [0 Inf];
            app.FcEditField.RoundFractionalValues = 'on';
            app.FcEditField.Value = 1600;
            app.ProgressTextAreaLabel = uilabel(app.UIFigure);
            app.ProgressTextAreaLabel.Text = 'Progress';
            app.ProgressTextArea = uitextarea(app.UIFigure);
            app.CalculatePeaksCheckBox = uicheckbox(app.UIFigure);
            app.CalculatePeaksCheckBox.Text = 'Calculate Peaks';
            app.CalculatePeaksCheckBox.Value = true;
            app.ProcessfromtapEditFieldLabel = uilabel(app.UIFigure);
            app.ProcessfromtapEditFieldLabel.Text = 'Process from tap';
            app.tap1Field = uieditfield(app.UIFigure, 'numeric');
            app.tap1Field.Limits = [1 Inf];
            app.tap1Field.Value = 1;
            app.toEditFieldLabel = uilabel(app.UIFigure);
            app.toEditFieldLabel.Text = 'to';
            app.tap2Field = uieditfield(app.UIFigure, 'numeric');
            app.tap2Field.Limits = [1 Inf];
            app.tap2Field.Value = 1;
            app.AlltapsCheckBox = uicheckbox(app.UIFigure);
            app.AlltapsCheckBox.Text = 'All taps';
            app.AlltapsCheckBox.Value = true;
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'Radon Transform', 'Surface Fit', 'Curve Fit'};
            app.MethodDropDown.Value = 'Radon Transform';
            app.CalculateDisplacementCheckBox = uicheckbox(app.UIFigure);
            app.CalculateDisplacementCheckBox.Text = 'Calculate Displacement';
            app.CalculateDisplacementCheckBox.Value = true;
            app.SelectROIforSWSprocessingoptionalLabel = uilabel(app.UIFigure);
            app.SelectROIforSWSprocessingoptionalLabel.Tooltip = {'This will pop out an ultrasound image to choose the region of interest (ROI) to reduce calculations needed.'};
            app.SelectROIforSWSprocessingoptionalLabel.Text = 'Select ROI for SWS processing (optional):';
            app.SelectROICheckBox = uicheckbox(app.UIFigure);
            app.SelectROICheckBox.Text = 'Select ROI';
            app.ParallelComputeCheckBox = uicheckbox(app.UIFigure);
            app.ParallelComputeCheckBox.Text = 'Use parallel computing toolbox';
            app.ParallelComputeCheckBox.Value = true;
            app.UseparallelcomputingtoolboxfasterLabel = uilabel(app.UIFigure);
            app.UseparallelcomputingtoolboxfasterLabel.Tooltip = {'This will pop out an ultrasound image to choose the region of interest (ROI) to reduce calculations needed.'};
            app.UseparallelcomputingtoolboxfasterLabel.Text = 'Use parallel computing toolbox (faster):';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calculateDisplacement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end