classdef visualiseHFR_Radon < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        LoadMenu                    matlab.ui.container.Menu
        ROIMenu                     matlab.ui.container.Menu
        SelectROIDepthsMenu         matlab.ui.container.Menu
        ClearROIMenu                matlab.ui.container.Menu
        SaveROIMenu                 matlab.ui.container.Menu
        VisualiseROIAllTapsMenu     matlab.ui.container.Menu
        SumthresholdEditField       matlab.ui.control.NumericEditField
        SumthresholdEditFieldLabel  matlab.ui.control.Label
        TapDisplay                  matlab.ui.control.NumericEditField
        ShowvideoButton             matlab.ui.control.Button
        lengthField                 matlab.ui.control.NumericEditField
        mmEditFieldLabel            matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        lengthSlider                matlab.ui.control.Slider
        LengthLabel                 matlab.ui.control.Label
        startWidthSlider            matlab.ui.control.Slider
        StartwidthSliderLabel       matlab.ui.control.Label
        RotationSpinner             matlab.ui.control.Spinner
        RotationSpinnerLabel        matlab.ui.control.Label
        CurrentfileEditField        matlab.ui.control.EditField
        CurrentfileEditFieldLabel   matlab.ui.control.Label
        TapSlider                   matlab.ui.control.Slider
        TapSliderLabel              matlab.ui.control.Label
        depthSlider                 matlab.ui.control.Slider
        SliderLabel                 matlab.ui.control.Label
        axSWSoverTapLine            matlab.ui.control.UIAxes
        axSWSoverTap                matlab.ui.control.UIAxes
        axDisp                      matlab.ui.control.UIAxes
        axDispWidth                 matlab.ui.control.UIAxes
        axImSWS                     matlab.ui.control.UIAxes
        axImBmode                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        US % ultrasound data
        displ % displacement data
        process % processing parameters
        flags % for flagging behaviours
        viz % visualization parameters
    end
    
    methods (Access = private)
        
        function updateDisplay(app)
            
            
            cla(app.axDisp,'reset')
            cla(app.axDispWidth,'reset')
            %             cla(app.axSWSoverTap,'reset')
            cla(app.axImBmode,'reset')
            cla(app.axImSWS,'reset')
            
            %             I = app.US.I;
            %             Q = app.US.Q;
            
            Depth = app.viz.Depth;
            Width = app.viz.Width;
            %             Frames= app.viz.Frames;
            %             nTaps = app.viz.nTaps;
            
            
            wx = app.viz.wx;
            dx = app.viz.dx;
            tx = app.viz.tx;
            
            cmap = magma(Width);
            
            tp = app.viz.currentTap ;
            wI = (app.viz.currentWidth(1):app.viz.currentWidth(2));
            
            dI = app.viz.linedI;
            wxLine = app.viz.linewx;
            dxLine = app.viz.linedx;
            
            wx_up = app.viz.wx_up;
            dx_up = app.viz.dx_up;
            
            % Plot the bmode
            imagesc(app.axImBmode,wx_up,dx_up,   app.viz.bmode{tp})
            
            hold(app.axImBmode,'on')
            caxis(app.axImBmode,[3,max( app.viz.bmode{tp},[],'all')])


            % plot red depth line
            app.viz.bmodeRedLine = plot(app.axImBmode ,wxLine([1,end]),[dxLine([1, end])],'r');
            axis(app.axImBmode,'tight')
            ylim(app.axImBmode,[dx(1),dx(end)])
            
            
            colormap(app.axImBmode,gray(256));
            
            disp_plot = [];
            for ii = 1:length(dI) % for each element in the depth (and width if along a non horizontal line)
                
                % colour fake transducer elements to correspond with
                % the width on the bmode
                plot(app.axImBmode, wxLine(ii),dx(1),'s','markerfacecolor',cmap(wI(ii),:),'Markeredgecolor','k')
                
                % put the displacement data for each selected pixel in a
                % matrix
                disp_plot(ii,:) =  squeeze(app.displ.filt_fft(dI(ii),wI(ii),:,tp)*10^6);
            end
            
            % plot the displacement over the whole width
            app.viz.h_widthWaveIm = imagesc(app.axDispWidth ,tx,wxLine, disp_plot);
            hold(app.axDispWidth,'on');
            axis(app.axDispWidth,'tight');
            caxis(app.axDispWidth,[-0.05 0.05])
            colormap(app.axDispWidth,gray(256))
            app.viz.h_dispCurves = [];
            app.viz.h_SWS_x = [];
            app.viz.h_dispWidth_x = [];
            wat_vals = zeros(1,length(dI));
            for ii = 1:length(dI)
                
                % plot all the displacement curves for the depth
                app.viz.h_dispCurves(ii) = plot(app.axDisp,tx, disp_plot(ii,:),'color',cmap(wI(ii),:));
                %                 plot(app.axDisp,tx, squeeze(app.displ.raw(dI(ii),wI(ii),:,tp)*10^6),'color',cmap(wI(ii),:));
                hold(app.axDisp,'on')
                %
                %                 if app.flags.peakTimes(tp) == 1
                %
                %
                %                     % plot the peaks on top of the waves
                %                     app.viz.h_dispWidth_x(ii) =plot(app.axDispWidth, app.displ.WAT{tp}(dI(ii),wI(ii)),wx(wI(ii)),'x','color',cmap(wI(ii),:));
                %                     hold(app.axDispWidth,'on');
                %
                %                     % plot width vs arrival time as shear wave speed
                %                     app.viz.h_SWS_x(ii) = plot(app.axDispSWS,app.displ.WAT{tp}(dI(ii),wI(ii)),app.displ.lineLengths(ii),'x','color',cmap(wI(ii),:));
                %                     hold(app.axDispSWS,'on');
                %
                %                     wat_vals(ii) = app.displ.WAT{tp}(dI(ii),wI(ii));
                %                 end
                
            end
            
            
            %
            if app.flags.peakTimes(tp) == 1
                %
                % %                 app.axImWaveTime.Visible = 'on';
                % %                 app.axOverlay.Visible = 'on';
                % %                 app.axColorbar.Visible = 'on';
                % %                 app.axDispSWS.Visible = 'on';
                app.axImSWS.Visible = 'on';
                visualiseLineTaps(app)
                %
                %                 % the bmode image underneath
                %
                %                 imagesc(app.axImWaveTime,wx_up,dx_up, app.viz.bmode{tp})
                %                 caxis(app.axImWaveTime,[3,max( app.viz.bmode{tp},[],'all')])
                %                 colormap(app.axImWaveTime,gray(256))
                %                 axis(app.axImWaveTime,'tight');
                %
                %                 ylim(app.axImWaveTime,[dx(1),dx(end)])
                %
                %                 % the overlaid time of wave arrival
                %                 him=imagesc(app.axOverlay,wx,dx,app.displ.WAT{tp});
                %
                %                 axis(app.axOverlay,'tight');
                %                 him.AlphaData = 0.35;
                %                 hold(app.axOverlay,'on')
                %                 app.axOverlay.Position = app.axImWaveTime.Position;
                %
                %
                %                 colormap(app.axOverlay,jet(256))
                %                 app.axOverlay.CLim = [prctile(app.displ.WAT{tp}(:),2) prctile(app.displ.WAT{tp}(:),85)];
                %                 app.viz.overlayRedLine = plot(app.axOverlay,wxLine([1,end]),[dxLine([1, end])],'r');
                %
                %                 app.axOverlay.XLim = app.axImWaveTime.XLim;
                %                 app.axOverlay.YLim = app.axImWaveTime.YLim;
                %
                %                 xlabel(app.axOverlay,'Width (mm)')
                %                 ylabel(app.axOverlay,'Depth(mm)')
                %                 app.axOverlay.Color = 'none';
                %
                %                 colormap(app.axColorbar,jet);
                %                 colorbar(app.axColorbar)
                %                 app.axColorbar.Position([2,4]) = app.axImWaveTime.Position([2,4]);
                %                 app.axColorbar.CLim = [prctile(app.displ.WAT{tp}(:),2) prctile(app.displ.WAT{tp}(:),85)];
                %                 axis(app.axColorbar,'off')
                %
                % make every shear wave value an index for the color
                % map
                cmapV = flipud(viridis(100));%viridis(max(inde));
                cmapV = [cmapV;repmat(cmapV(end,:),100,1)];
                cmapRsq = hsv(64);
                cmapRsq = cmapRsq([20,10,1],:); % green, orange, red
                hold(app.axImSWS,'on')
                app.axImSWS.YDir = 'reverse';
                
                if ~isfield(app.displ,'SWS_ROI') % if a region of interest is not specified
                    inde = round(abs(app.displ.SWS{tp}))+1;
                    inde(inde>200) = 200;
                    inde(inde<1) = 1;
                    % plot the SWS
                    for dd = 1:Depth
                        if isnan(inde(dd))
                            continue
                        end
                        Rsq = app.displ.Rsq{tp}(dd);
                        
                        %                         if isfield(app.displ,'gof_save') % for the surface
                        %                         Rsq = app.displ.gof_save{tp}(dd).rsquare;
                        if Rsq < 0.20
                            col = cmapRsq(3,:);
                        elseif Rsq >= 0.20 && Rsq < 0.5
                            col = cmapRsq(2,:);
                        else
                            col = cmapRsq(1,:);
                        end
                        
                        transp = 1;%app.displ.Rsq{tp}(dd);
                        
                        barh(app.axImSWS,[dx(dd)],abs(app.displ.SWS{tp}(dd)),'facecolor',cmapV(inde(dd),:),'facealpha',transp,'BarWidth',diff(dx(1:2)),'edgecolor','none')
                        
                        % plot the Rsq value on the left
                        barh(app.axImSWS,[dx(dd)],-app.displ.Rsq{tp}(dd)/50,'facecolor',col,'BarWidth',diff(dx(1:2)),'edgecolor','none')
                        
                    end
                    plot(app.axImSWS,-[app.viz.sum_thresh,app.viz.sum_thresh]/50,dx([1,Depth]),"Color",'k' )
                else % we are looking at a region of interest
                    
                    fill(app.axImBmode,app.viz.ROIcorners(1,:),app.viz.ROIcorners(2,:),'r',"FaceAlpha",0.5,'EdgeColor',"none")
                    
                    indeROI = round(abs(app.displ.SWS_ROI{tp}))+1;
                    indeROI(indeROI>200) = 200;
                    indeROI(indeROI<1) = 1;
                    for dd = 1:length(app.displ.SWS_ROI{tp})
                        Rsq = app.displ.Rsq{tp}(dd);
                        
                        %                         if isfield(app.displ,'gof_save') % for the surface
                        %                         Rsq = app.displ.gof_save{tp}(dd).rsquare;
                        if Rsq < 0.20
                            col = cmapRsq(3,:);
                        elseif Rsq >= 0.20 && Rsq < 0.5
                            col = cmapRsq(2,:);
                        else
                            col = cmapRsq(1,:);
                        end
                        
                        if isnan(indeROI(dd))
                            continue
                        end
                        barh(app.axImSWS,app.viz.dxROI(dd),abs(app.displ.SWS_ROI{tp}(dd)),'facecolor',cmapV(indeROI(dd),:),'BarWidth',diff(dx(1:2)),'edgecolor','none')
                        barh(app.axImSWS,[dx(dd)],-app.displ.Rsq{tp}(dd)/50,'facecolor',col,'BarWidth',diff(dx(1:2)),'edgecolor','none')
                        
                        % NOte: clean up the diff(dx) by adding a new
                        % variable - not vital; just housekeeping
                        
                        % plot the Rsq value on the left
                        %                         barh(app.axImSWS,app.viz.dxROI(dd),-app.displ.Rsq_ROI{tp}(dd)*5,'facecolor',col,'BarWidth',diff(dx(1:2)),'edgecolor','none')
                        
                    end
                    plot(app.axImSWS,-[app.viz.sum_thresh,app.viz.sum_thresh]/50,dx([1,end]),"Color",'k' )
                    visualiseAllTaps(app)
                    
                end
                grid(app.axImSWS,'minor')
                axis(app.axImSWS,'tight');
                xlim(app.axImSWS,[-5 150]);
                app.viz.SWSRedLine = plot(app.axImSWS, xlim(app.axImSWS),[dx([dI(1), dI(2)])],'r');
                xlabel(app.axImSWS,'');
                colormap(app.axImSWS,cmapV)
                app.axImSWS.CLim = [0 size(cmapV,1)];
                colorbar(app.axImSWS)
                
                
                %                 % Rsq label ALWAYS does the whole line
                %                 if isfield(app.displ,'gof_save')
                %
                %                     Rsq = app.displ.gof_save{tp}(dI(1)).rsquare;
                %
                %                 else
                %                     Rsq = app.displ.Rsq{tp}(dI(1));
                %                 end
                %                 app.LabelRSq.Text = sprintf('Whole line:\nR^2 = %0.2f',Rsq);
                %                 app.LabelSWS.Text = sprintf('\nSWS = %0.0f m/s',app.displ.SWS{tp}(dI(1)));
                
                %                 xx = xlim(app.axDispSWS);
                %                 yy = ylim(app.axDispSWS);
                %                 app.viz.h_textSWS = text(app.axDispSWS,xx(1),yy(1)+0.2*diff(yy), sprintf('SWS = %0.0f m/s',app.displ.SWS_line));
                %                 app.viz.h_textRsq = text(app.axDispSWS,xx(1),yy(1)+0.4*diff(yy), sprintf('Rsq = %0.2f',app.displ.Rsq_line));
                %                 %                 ttx = [min(app.displ.WAT,[],'all'):0.1:max(app.displ.WAT,[],'all')] ; % shortened time
                %                 Y = app.displ.mdl_line_formula(wat_vals); % get the line eq
                %                 app.viz.h_regressLine = plot(app.axDispSWS,wat_vals,Y); % plot the regression line
                %
                %             else
                %
                %                 app.axImWaveTime.Visible = 'off';
                %                 app.axOverlay.Visible = 'off';
                %                 app.axColorbar.Visible = 'off';
                %                 app.axDispSWS.Visible = 'off';
                %                 app.axImSWS.Visible = 'off';
            end
            
            
            xlabel(app.axDisp,'Time (ms)')
            ylabel(app.axDisp,'Displacement (um)')
            xlabel(app.axDispWidth,'Time (ms)')
            ylabel(app.axDispWidth,'Width (mm)')
            xlabel(app.axImBmode,'Width (mm)')
            ylabel(app.axImBmode,'Depth(mm)')
            
            xlabel(app.axImSWS,'SWS over the width (m/s)')
            ylabel(app.axImSWS,'Depth(mm)')
            
            
        end
        
        function app = quickUpdateDisplay(app)
            
            % update the red lines on the bmode image and colourful wave
            % arrival overlay
            wxLine = app.viz.linewx;
            dxLine = app.viz.linedx;
            dI = app.viz.linedI;
            wI = app.viz.linewI;
            nD =length(dI);
            
            app.viz.bmodeRedLine.YData = dxLine([1, end]);
            app.viz.overlayRedLine.YData = dxLine([1, end]);
            
            % update the width image data
            
            disp_plot = nan(nD,app.viz.Frames); % initialise
            for ii = 1:nD% for each element in the depth (and width if along a non horizontal line)
                
                % put the displacement data for each selected pixel in a
                % matrix
                disp_plot(ii,:) =  squeeze(app.displ.filt_fft(dI(ii),wI(ii),:,app.viz.currentTap)*10^6);
                
                % plot all the displacement curves for the depth
                set(app.viz.h_dispCurves(ii),'YData',disp_plot(ii,:));
                
                
            end
            
            % plot the displacement over the whole width
            app.viz.h_widthWaveIm.CData = disp_plot;
            
            visualiseLineTaps(app)
            
        end
        
        function app = loadFiles(app)
            
            %             app.flags.peakTimes = 0; % have we calculated the peak times yet
            app.flags.ROI = 0; % we have no region of interest
            % load the file, looking in the most recent folder if available
            if isfield(app.US,'path')
                
                [file,path] = uigetfile(app.US.path);
            else
                
                [file,path] = uigetfile('E:\Wisconsin\*.mat');
            end
            
            if file == 0
                return
            else % reset the app
                app.US = [];
                app.displ = [];
                app.process = [];
                app.flags.peakTimes = 0;
                app.viz = [];
            end
            
            app.US = load(fullfile(path,file)); % load the ultrasound data;
            app.US.path = path;
            app.US.filename = fullfile(path,file);
            app.CurrentfileEditField.Value = app.US.filename;


            if exist(fullfile(path,[file(1:end-4) '_displacement.mat']),'file') == 2
                raw_disp =  load(fullfile(path,[file(1:end-4) '_displacement.mat']));
                
                app.displ.raw = raw_disp.displ;
                app.displ.filt = raw_disp.displ_filt;
                app.displ.filt_fft = raw_disp.displ_filt_fft;
                
            else
                measureDisplacement(app)
            end


            [Depth,Width,Frames,nTaps] = size(app.displ.raw);
            app.viz.Depth = Depth;
            app.viz.Width = Width;
            app.viz.Frames = Frames;
            app.viz.nTaps = nTaps;
            app.viz.currentTap = 1;
            app.TapDisplay.Value = app.viz.currentTap;
            %             app = filterDisplacement(app); % apply the 2D filter
            
            app.flags.peakTimes = zeros(nTaps,1);
            app.viz.sum_thresh = app.SumthresholdEditField.Value;
            
            % set up the slider - we have to set it up backwards to have it
            % make sense
            app.depthSlider.MajorTicks = round(linspace(1,Depth,5));
            app.depthSlider.MajorTickLabels = flip(app.depthSlider.MajorTickLabels);
            app.depthSlider.Limits = [0,Depth];
            app.viz.currentDepth = Depth-app.depthSlider.Value;
            
            wlmm = 1540/app.US.Trans.frequency * 10^-3;
            wx = 0:app.US.Trans.spacingMm:(Width-1)*app.US.Trans.spacingMm;
            
            wxRF = wx(1:2:end); % every second transducer is currently being used for RF; may change in future
            
            % NOTE: there is 2x the number of samples for some reason....
            % Probably a there and back thing, but Verasonics states that:
            % size of each acquisition segment in samples is given by:
            % 2*(Receive.endDepth – Receive.startDepth)*Resource.RcvBuffer.samplesPerWave
            rf_divs = wlmm/app.US.Receive(1).samplesPerWave/2;
            dxRF = app.US.Receive(1).startDepth*wlmm:rf_divs:(app.US.Receive(1).endDepth)*wlmm-rf_divs; % The RF data is deeper
            dx = app.US.P.startDepth*wlmm:wlmm*app.US.PData.PDelta(3):(app.US.P.endDepth)*wlmm; % NOTE: it's divided by 2 because PData.Pdelta(3) = 0.5, meaning two samples per wavelength
            %               dx = app.US.P.startDepth*wlmm:wlmm/2:(app.US.P.endDepth)*wlmm; % NOTE: it's divided by 2 because PData.Pdelta(3) = 0.5, meaning two samples per wavelength
            
            dt = 50E-3;
            tx = 0:dt:(Frames-1)*dt;
            
            app.viz.wx = wx;
            app.viz.wxRF = wxRF;
            app.viz.dx = dx;
            app.viz.dxRF = dxRF;
            app.viz.tx = tx;
            
            % the bmode images only need to be calculated once
            app = calculateBmode(app);
            
            % Set the ticks of the tap slider
            app.TapSlider.MajorTicks = round(linspace(1,nTaps,5));
            app.TapSlider.Value = 1;
            app.TapSlider.MinorTicks = 1:1:nTaps;
            app.TapSlider.Limits = [1,nTaps];
            % set the ticks of the width bar
            majorTicks = round([0:Width/4:Width-1]*app.US.Trans.spacingMm,1);
            minorTicks = [0:Width-1]*app.US.Trans.spacingMm;
            app.startWidthSlider.MajorTicks = majorTicks;
            app.startWidthSlider.MinorTicks = minorTicks;
            app.startWidthSlider.Value = 0;
            app.startWidthSlider.Limits = [0,(Width-1)*app.US.Trans.spacingMm];
            
            app.lengthSlider.MajorTicks = majorTicks;
            app.lengthSlider.MinorTicks = minorTicks;
            app.lengthSlider.Value = (Width-1) *app.US.Trans.spacingMm;
            app.lengthSlider.Limits = [5*app.US.Trans.spacingMm,(Width-1)*app.US.Trans.spacingMm];
            
            
            app.lengthField.Value = (Width-1) *app.US.Trans.spacingMm;
            app.lengthField.Limits = [5*app.US.Trans.spacingMm,(Width-1)*app.US.Trans.spacingMm];
            
            app.viz.currentWidthMM = [0, app.lengthSlider.Value];
            app.viz.currentWidth = [1, Width];
            
            % check to see if the wave arrival times have been saved
            if exist( [app.US.filename(1:end-4) '_radon.mat'],"file") == 2
                load( [app.US.filename(1:end-4) '_radon.mat'])
                uiconfirm(app.UIFigure,sprintf('File loaded : %s', [app.US.filename(1:end-4) '_radon.mat']),'Ok?',...
                    "Options",{'OK'})
                
                %                 if ~iscell(wave_arrival_time)
                %                     app.displ.WAT{1} = wave_arrival_time; % to preserve compatibility before we had other taps
                %                     app.displ.SWS{1} = SWS;
                %                     app.displ.Rsq{1} = Rsq; % Rsquared confidence in the linear fit
                %                     app.displ.mdl{1} = mdl;
                %
                %                     app.flags.peakTimes(1) = 1;
                %
                %                 else
                %
                %                     app.displ.WAT = wave_arrival_time;
                app.displ.SWS = SWS;
                app.displ.Rsq = Rsq; % Rsquared confidence in the linear fit
                %                     app.displ.mdl = mdl;
                %
                %                     app.displ.gof_save = gof_save; % Rsquared confidence in the surface fit
                %
                
                ind_dat = cellfun(@isempty,app.displ.SWS); % where there is data stored in the cells (i.e. peaks are calculated for some taps not others)
                
                app.flags.peakTimes(~ind_dat) = 1;
                %                 end


            end
            resetWaveSpeed(app)
            updateDisplay(app)
            
        end
        
        
        function app = calculateBmode(app)
            
            
            wxRF = app.viz.wxRF;
            dxRF = app.viz.dxRF;
            
            wx_up = wxRF(1):diff(wxRF(1:2))/4:wxRF(end); % upsample the width by 4
            dx_up = dxRF(1):diff(dxRF(1:2))/8:dxRF(end); % upsample the depth by 8
            [~,~,a,b] = size(app.US.RF); % depending on when the data was collected, it could be a tap or a frame
            for t = 1:app.viz.nTaps
                %                 rf_env = [];
                %                 for f = 1%:app.viz.Frames
                if a > 1 && b > 1 % historical, when all taps were included
                    rf_env(:,:,t) = envelope(double(app.US.RF(:,:,1,t)),25,'analytic');
                else
                    rf_env(:,:,t) = envelope(double(app.US.RF(:,:,t,1)),25,'analytic');
                end
                %                 end
                %                 rf_env = mean(rf_env,3); % average the envelope over all frames of the tap
                F = griddedInterpolant({dxRF,wxRF},rf_env(:,:,t)); % set up the interpolant
                rf_im = F({dx_up,wx_up}); % interpolate over the additional points
                rf_im = medfilt2(rf_im,[5,3]);
                
                imlog = log(1+abs(rf_im));
                
                app.viz.bmode{t} = imlog;
            end
            app.viz.wx_up = wx_up;
            app.viz.dx_up = dx_up;
        end
        
        
        function measureDisplacement(app)
            
            
            I = app.US.I;
            Q = app.US.Q;
            
            %             tp = 1;% tap number to look at - update in future


            M = 5;
            N = 3;
            f_cutoff = 1600;
            tap_nums = 1;
            fw = waitbar(0.05,'Calculating displacement...');
            %% calculate the displacement
            [displ,displ_filt] = LoupasDisplacement(I(:,:,:,tap_nums),Q(:,:,:,tap_nums),app.US.Trans,app.US.Resource,app.US.SeqControl,  M,N,f_cutoff);
            
            waitbar(1,fw,'Calculating displacement...');
            
            app.displ.raw = displ;
            app.displ.filt = displ_filt;
            
            
            app.process.M = M;
            app.process.N = N;
            app.process.fc = f_cutoff;
            app.process.taps_proc = tap_nums;
            close(fw)
            
        end
        
        function findPeakTimes(app)
            
            msg= 'Do you want to find the peaks of all taps or the current tap?';
            answer = uiconfirm(app.UIFigure,msg,'Save Options',...
                'Options',{'Current Tap', 'All Taps','Cancel'},...
                'DefaultOption',1,...
                'CancelOption',3);
            switch answer
                case 'Current Tap'
                    tps = app.viz.currentTap;
                case 'All Taps'
                    tps = find(app.flags.peakTimes==0)';
                otherwise
                    return
            end
            
            app.flags.peakTimes(tps) = 1;
            
            
            Depth = app.viz.Depth;
            Width = app.viz.Width;
            Frames= app.viz.Frames;
            nTaps = app.viz.nTaps;
            
            wx = app.viz.wx;
            dt = 50E-3;
            
            tx =0:dt:(Frames-1)*dt;
            fw = waitbar(0,'Fitting curves...');
            
            rng = 5; %number of data points to look around the peak value
            
            % for the curve fit to figure out where the first peak is.
            % of the gaussian pulse
            pulse_centre = 1.5;
            pulse_width = 0.5;
            
            % of the sine
            pk_mag = 0.05;
            pk_width = 7.5;
            pk_offset = 1;
            % initial conditions (tuned by LW)
            x0 = [pulse_centre, pulse_width, pk_mag, pk_width, pk_offset]; % pulse centre, width of pulse, magnitude of peaks, pk to pk width of sine
            
            % gaussian pulse mixed with a sine wave
            pulse_func = @(a,b,c,d,e,x) exp(-(x-a).^2/(2*b.^2)).*c .* sin(d*x - e);
            pulse_s = fittype(pulse_func );
            
            
            t_ind = 1;
            for tp = tps
                wave_arrival_time = nan(Depth,Width);


                for d = 1:Depth
                    
                    waitbar(((t_ind-1)*Depth+d)/(Depth*length(tps)),fw,'Fitting curves...');
                    disp_env = medfilt2(squeeze(app.displ.filt_fft(d,:,:,tp)),[15,3]);
                    
                    w_vals = round(Width* [0.1:0.02:0.9]); % at various widths
                    w_vals_save = [];
                    
                    % get an estimate of where the peak values are by measuring
                    % the envelope around the displacement
                    II = 1;
                    Ix_lims_crop = [];
                    
                    %                     figure(10)
                    %                     hold off;
                    for w = w_vals


                        disp_sq = disp_env(w,:)';%squeeze(app.displ.filt_fft(d,w,:,tp));
                        rdd = range(disp_sq);
                        x0(3) = rdd*10^6/2;
                        % fit the curve to the wave; note that we have to
                        % multiply the displacement to get function changes
                        % that are meaningful to the optimizer
                        [fitted_curve,gof] = fit(tx',disp_sq*10^6,pulse_s,'StartPoint',x0,'tolfun',10e-7);
                        fit_vals = fitted_curve(tx);
                        %
                        %                         k = strfind(out(w,:),[0 1]);
                        %                         k = k(1);
                        
                        [~,Imax] = findpeaks(fit_vals,'NPeaks',1,"MinPeakWidth",4,'MinPeakHeight', x0(3)/10);
                        %                         hold off;plot(tx,fit_vals)
                        %                         hold on;
                        %                         plot(tx',disp_sq*10^6,'.')
                        %                         plot(tx(Imax),fit_vals(Imax),'x')
                        if isempty(Imax)
                            continue
                        end


                        if Imax + rng >= Frames
                            %                             tx_lims_crop(II,1:2) = tx([Imax-rng,Frames]);
                            Ix_lims_crop(II,1:2) = [Imax-rng*2,Frames];
                        elseif Imax -rng <= 0
                            %                             tx_lims_crop(II,1:2) = tx([1,Imax+rng]);
                            Ix_lims_crop(II,1:2) = [1,Imax+rng*2];
                        else
                            %                             tx_lims_crop(II,1:2) = tx([Imax-rng,Imax+rng]);
                            Ix_lims_crop(II,1:2) = [Imax-rng,Imax+rng];
                        end
                        w_vals_save(1,II) = w;
                        %                         plot(tx(Ix_lims_crop(II,1:2)),disp_env(Ix_lims_crop(II,1:2)),'o')
                        II = II + 1;
                    end
                    % add the beginning and end elements of width without
                    % measuring; can be noisy
                    w_vals_save(1:end+2) = [1, w_vals_save,Width] ;
                    Ix_lims_crop(2:end+1,:) =Ix_lims_crop;
                    Ix_lims_crop(end+1,:) = Ix_lims_crop(end,:);
                    
                    %                     Ix_lims(:,1) = round(interp1q(w_vals',Ix_lims_crop(:,1),[1:Width]'));
                    %                     Ix_lims(:,2) = round(interp1q(w_vals',Ix_lims_crop(:,2),[1:Width]'));
                    %
                    % set up a linear fit to the upper and lower bounds - gets rid of outliers
                    b1 = [ones(1,length(w_vals_save)),;w_vals_save]'\Ix_lims_crop(:,1); % left bound
                    b2 = [ones(1,length(w_vals_save)),;w_vals_save]'\Ix_lims_crop(:,2); % left bound
                    
                    Ix_lims(:,1) = b1(1) + b1(2) * [1:Width];
                    Ix_lims(:,2) = b2(1) + b2(2) * [1:Width];
                    
                    Ix_lims = floor(Ix_lims);
                    ind1 = Ix_lims(:,1) < 1;
                    Ix_lims(ind1,:) = Ix_lims(ind1,:) - Ix_lims(ind1,1) + 1; % shift the range
                    
                    indbig = Ix_lims(:,2) > Frames;
                    Ix_lims(indbig,:) = Ix_lims(indbig,:) - (Ix_lims(indbig,2) - Frames) ; % shift the range
                    Ix_lims(Ix_lims>Frames) = Frames;
                    
                    
                    %                     figure;hold on;
                    %                     plot(w_vals',tx_lims_crop(:,1),'o')
                    %                     plot([1:Width]',tx_lims(:,1))
                    %                     plot(w_vals',tx_lims_crop(:,2),'o')
                    %                     plot([1:Width]',tx_lims(:,2))
                    %                     tx_lims(:,2) = interp1q(w_vals',tx_lims_crop(:,2),1:Width);
                    
                    for w = 1:Width
                        
                        disp_sq = squeeze(app.displ.filt_fft(d,w,:,tp)*10^6); % squeezed displacement data, shows time for each width and depth
                        
                        
                        %                         I_fit = tx > tx_lims(w,1) & tx < tx_lims(w,2);
                        %                         f = fit(tx(I_fit)',disp_sq(I_fit),'poly6'); %fit a 6th order polynomial
                        I_fit = Ix_lims(w,1):Ix_lims(w,2);
                        %                         I_fit(I_fit < 1) = 1;
                        %                         I_fit(I_fit > Width) = Width;
                        f = fit(tx(I_fit)',disp_sq(I_fit),'poly6'); %fit a 6th order polynomial
                        %                         if w == 128 && d==70
                        %                             d
                        %                         end
                        t_small = linspace(tx(Ix_lims(w,1)),tx(Ix_lims(w,2)),10000); % create a fine grid of time points to numerically evaluate the peak
                        
                        
                        ff = feval(f,t_small); % evaluate the curve fit at these points
                        %                         figure(10);hold off; plot(t_small,ff); hold on;
                        %                         plot(tx(I_fit)',disp_sq(I_fit),'.')
                        [~,It] = findpeaks(ff,'NPeaks',1);
                        if isempty(It)
                            %                             pause(0.1)
                            wave_arrival_time(d,w) = NaN;
                        else
                            
                            wave_arrival_time(d,w) = t_small(It); % save the time of wave arrival
                        end
                        %                         [~,It] = max(ff); % find the peak displacement (or wave arrival time)
                        
                    end
                    
                    mdl = fitlm(wave_arrival_time(d,:),wx); % linear fit to the width travelled and the arrival time; slope is SWS in m/s
                    
                    SWS(d) = mdl.Coefficients{2,1};
                    Rsq(d) = mdl.Rsquared.Ordinary;
                end
                
                app.displ.WAT{tp} = wave_arrival_time;
                
                app.displ.SWS{tp} = SWS;
                app.displ.Rsq{tp} = Rsq; % Rsquared confidence in the linear fit
                app.displ.mdl{tp} = mdl;
                
                t_ind = t_ind + 1;
            end
            resetWaveSpeed(app)
            close(fw)
        end
        
        function app = resetWaveSpeed(app)
            % to update the wave speed every time the rotation or width is
            % changed without recalculating the peaks
            %             app.flags.changeLine = 1;
            
            % set up a line with a rotation value
            rot = app.RotationSpinner.Value;
            h2w = tand(-rot); % slope (height to width ratio)
            
            % note that the currentWidth/currentDepth are indices in the
            % matrix, width is in the x, but represents columns, depth is
            % in the y, but represents rows.
            pt1 = [app.viz.currentWidth(1),app.viz.currentDepth]; % the left most point of the line
            tp = app.viz.currentTap;
            
            b = pt1(2) - h2w*pt1(1); % intercept
            D = @(w) h2w*w + b; % line eq
            % the width values between the two lines
            wI = app.viz.currentWidth(1):app.viz.currentWidth(2);
            dI = round(D(wI)); % the depth values for each of the widths; because they are indices, must be rounded
            % add a catch here for if the depth values are no longer in the
            % image
            if any((dI < 0) | (dI > app.viz.Depth))
                
                ind_deep = dI > app.viz.Depth;
                dI(ind_deep) = [];
                wI(ind_deep) = [];
                
                ind_shallow = dI <= 0;
                dI(ind_shallow) = [];
                wI(ind_shallow) = [];
                
                % we've cropped the width, so update the width sliders
                app.viz.currentWidth = wI([1,end]);
                app.startWidthSlider.Value = wI(1) * diff(app.viz.wx(1:2));
                app.lengthSlider.Value = wI(end) * diff(app.viz.wx(1:2));
            end
            wx = app.viz.wx(wI);% the mm values for width
            dx = app.viz.dx(dI); % the mm values for depth
            %             lineLengths = sqrt((wx-wx(1)).^2 + (dx-dx(1)).^2);  % distance travelled is no longer along width
            %
            %             if app.flags.peakTimes(tp) == 1
            %                 for ii = 1:length(wI)
            %                     wat(ii) = app.displ.WAT{tp}(dI(ii),wI(ii)); % wave arrival time at each point along the line
            %                 end
            %
            %                 %                 mdl = fitlm(wat,lineLengths,'linear'); % linear fit to the width travelled and the arrival time; slope is SWS in m/s
            %                 timex = [ones(1,length(wat));wat]';
            %                 b1 = timex\lineLengths'; % coefficients for linear fit
            %                 ll_pred = timex* b1;
            %                 SWS_line = b1(2);%mdl.Coefficients{2,1};
            %                 Rsq_line = 1 - sum((lineLengths' - ll_pred).^2)/sum((lineLengths' - mean(ll_pred)).^2);
            %                 %                 Rsq_line = mdl.Rsquared.Ordinary;
            %
            %                 app.displ.SWS_line = SWS_line;
            %                 app.displ.Rsq_line = Rsq_line; % Rsquared confidence in the linear fit
            %                 %                 app.displ.mdl_line = mdl;
            %                 app.displ.mdl_line_formula = @(x) SWS_line*(x) + b1(1);
            %                 app.displ.lineLengths = lineLengths;
            %             end
            
            app.viz.linewx = wx;
            app.viz.linedx = dx;
            app.viz.linewI = wI;
            app.viz.linedI = dI;
            
        end
        
        
        function app = calculateROIWaveSpeed(app)
            
            app.flags.ROI = 1;
            
            addedDepth = app.viz.addedDepth;
            
            SWS_ROI = [];
            Rsq_ROI = [];
            wat_ROI = [];
            
            if app.RotationSpinner.Value == 0 && app.lengthSlider.Value == app.lengthSlider.Limits(2)
                % IF there is no change in rotation or length, we can use
                % the originally calculate Rsq and SWS because there's no
                % difference
                calc = 0;
            else
                calc = 1;
                
            end
            
            %
            %
            for tp = find(app.flags.peakTimes)'%1:app.viz.nTaps
                %                 rI = 1;
                %
                %                 if calc == 1
                %                     for dd = 0 : addedDepth % for each index of depth selected
                %                         for ii = 1:length(app.viz.linewI)
                %
                %                             wat(ii) = app.displ.WAT{tp}(app.viz.linedI(ii) + dd ,app.viz.linewI(ii)); % wave arrival time at each point along the line
                %
                %                         end
                %
                %                         timex = [ones(1,length(wat));wat]';
                %                         b1 = timex\app.displ.lineLengths'; % coefficients for linear fit
                %                         ll_pred = timex* b1;
                %                         SWS_line = b1(2);%mdl.Coefficients{2,1};
                %                         Rsq_line = 1 - sum((app.displ.lineLengths' - ll_pred).^2)/sum((app.displ.lineLengths' - mean(ll_pred)).^2);
                %
                %                         %                     mdl = fitlm(wat,app.displ.lineLengths,'linear'); % linear fit to the width travelled and the arrival time; slope is SWS in m/s
                %
                %                         SWS_ROI(rI) = SWS_line;%mdl.Coefficients{2,1};
                %                         Rsq_ROI(rI) = Rsq_line;%mdl.Rsquared.Ordinary;
                %                         wat_ROI(rI,:) = wat;
                %                         rI = rI+1;
                %                     end
                %                 else
                %                     % load them from the previously calculated
                %                     % relationships
                SWS_ROI = app.displ.SWS{tp}(app.viz.linedI:app.viz.linedI+addedDepth);%SWS_line;%mdl.Coefficients{2,1};
                %                     wat_ROI = app.displ.WAT{tp}(app.viz.linedI:app.viz.linedI+addedDepth,:);
                %
                %
                %                     if isfield(app.displ,'gof_save') % for the surface
                %                         Rsq = [app.displ.gof_save{tp}.rsquare];
                %
                %                     else % for just the line
                %                         Rsq = app.displ.Rsq_ROI{tp};
                %                     end
                %
                Rsq_ROI = app.displ.Rsq{tp}(app.viz.linedI:app.viz.linedI+addedDepth);%Rsq_line;%mdl.Rsquared.Ordinary;
                %
                %
                %                 end
                app.viz.ROIcorners = [app.viz.linewx([1,end]), fliplr(app.viz.linewx([1,end]))  ;...
                    app.viz.linedx([1,end]), fliplr(app.viz.linedx([1,end]))+app.viz.diffx*addedDepth]; % [x1, x2, x3, x4; y1, y2, y3, y4] in mm
                
                
                app.displ.SWS_ROI{tp}  = SWS_ROI;
                app.displ.Rsq_ROI{tp}  = Rsq_ROI; % Rsquared confidence in the linear fit
                %                 app.displ.wat_ROI{tp}  = wat_ROI;
                %                 app.displ.mdl_ROI{tp}  = mdl;
            end
            
            visualiseAllTaps(app)
            
        end
        
        function lampGreen(app)
            app.Lamp.Color = [0 1 0];
        end
        
        function lampRed(app)
            app.Lamp.Color = [1 0 0];
        end
        
        function lampYellow(app)
            app.Lamp.Color =  [0.9800    0.7300    0.2216];
        end


        function app = filterDisplacement(app)
            Width = app.viz.Width;
            Time = app.viz.Frames;
            pf = 10; % padding factor
            
            T = 50*10^-6; % Sampling period
            Fs = 1/T;            % Sampling frequency
            
            dw = app.US.Trans.spacingMm;% mm
            Lx = Time*pf;             % Length of signal
            % t = 0:T:(Lx-1)*T;        % Time vector
            fx = Fs*(-Lx/2:(Lx/2)-1)/Lx;
            
            Ly = Width*pf;
            fy = dw*Width*(-Ly/2:(Ly/2)-1)/Ly;
            
            
            Fc = [500,1600];
            Fc = 1600;
            Wn = Fc/(Fs/2);
            n = 4;
            [b,a] = butter(n,Wn,'low') ;
            
            for tp = 1:app.viz.nTaps
                for d = 1:app.viz.Depth
                    fft_var_raw = squeeze(app.displ.raw(d,:,:,tp));
                    
                    fft_var =[];
                    for w = 1:Width
                        fft_var(w,:) = filtfilt(b,a,fft_var_raw(w,:));
                    end
                    
                    % set up the filter
                    filtt = true(Width*pf,Time*pf);
                    
                    filtt(Width*pf/2:end,1:Time*pf/2) = 0;
                    filtt(1:Width*pf/2,Time*pf/2:end) = 0;
                    filtt(1:end,1)  = 0;
                    filtt(1:end,end) = 0;
                    filtt(1,1:end) = 0;
                    filtt(end,1:end) = 0;
                    
                    Y = (fft2(fft_var,Width*pf,Time*pf));
                    
                    Y_filt = filtt.*Y;
                    
                    
                    fft_filt = ifft2(Y_filt,Width*pf,Time*pf);
                    app.displ.filt_fft(d,:,:,tp) = real(fft_filt(1:Width,1:Time));
                end
            end
        end
        
        function playVideo(app)
            xlabel(app.viz.video.ax_im,'width (mm)')
            ylabel(app.viz.video.ax_im,'depth (mm)')
            
            axis(app.viz.video.ax,'off')
            
            caxis(app.viz.video.ax,[0 8e-8])
            
            set(app.viz.video.ax,'YDir','reverse')
            colormap(app.viz.video.ax,scimap('bucky'))
            
            % set the limits to either the region of interest or the
            % depth/width
            if isfield(app.viz,'ROIcorners')
                xlim(app.viz.video.ax,app.viz.ROIcorners(1,1:2));
                ylim(app.viz.video.ax,app.viz.ROIcorners(2,[1,3]));
                xlim(app.viz.video.ax_im,app.viz.ROIcorners(1,1:2));
                ylim(app.viz.video.ax_im,app.viz.ROIcorners(2,[1,3]));
            else
                
                xlim(app.viz.video.ax,app.viz.linewx([1,end]))
                ylim(app.viz.video.ax,app.viz.dx([1,end]))
                xlim(app.viz.video.ax_im,app.viz.linewx([1,end]))
                ylim(app.viz.video.ax_im,app.viz.dx([1,end]))
            end
            
            % plot the bmode image
            imagesc(app.viz.video.ax_im,app.viz.wx_up,app.viz.dx_up,app.viz.bmode{app.viz.currentTap});
            
            hold(app.viz.video.ax_im,'on')
            axis(app.viz.video.ax_im,'off')
            
            colormap(app.viz.video.ax_im,gray(256))
            app.viz.video.ax_im.Position = app.viz.video.ax.Position;
            
            caxis(app.viz.video.ax_im,[3,max( app.viz.bmode{app.viz.currentTap},[],'all')])


            im_data = medfilt3(app.displ.filt_fft(:,:,:,app.viz.currentTap),[9 9 1]);
            
            t = 1;
            
            for w = 1:app.viz.Width
                %                 im_vel(:,w,:) = diff(im_data(:,w,:),1,3)*app.US.CollectionParams.fastFrameRate;
                im_vel(:,w,:) = im_data(:,w,:); % not velocity
            end
            
            
            him =  imagesc(app.viz.video.ax,app.viz.wx,app.viz.dx,im_vel(:,:,t));%app.displ.filt_fft(:,:,t,app.viz.currentTap));
            him.AlphaData = 0.35;
            
            ht =  title(app.viz.video.ax,sprintf('Time = %0.3f ms',t*0.05));
            
            h2 = title(app.viz.video.ax_im,sprintf('Time = %0.3f ms',t*0.05));
            h2.Color = [1 1 1];
            drawnow limitrate
            
            
            while isvalid(ht) && isvalid(him) && t < app.viz.Frames
                ht.String = sprintf('Time = %0.3f ms',t*0.05);
                %                 him.CData = app.displ.filt_fft(:,:,t,app.viz.currentTap);
                %                 him.CData = medfilt2(app.displ.filt_fft(:,:,t,app.viz.currentTap),[7,7]);
                him.CData = im_vel(:,:,t);
                %                 shading interp
                
                drawnow limitrate
                pause(0.1)
                t = t+1;
            end
            
        end
        
        function app = buttonPlot(app)
            % Create a figure window
            app.viz.video.fig = uifigure;
            app.viz.video.fig.Color = [1 1 1];
            
            % Create a UI axes for the tendon
            app.viz.video.ax_im = uiaxes('Parent',app.viz.video.fig,...
                'Units','normalized',...
                'Position',[0.05 0.1 0.9 0.85]  );
            
            % Create a UI axes for the wave propagation
            app.viz.video.ax = uiaxes('Parent',app.viz.video.fig,...
                'Units','normalized',...
                'Position', [0.05 0.1 0.9 0.85]);
            
            
            % Create a push button
            btn = uibutton(app.viz.video.fig,'push',...
                'Text','Replay',...
                'Position',[10,10, 100, 20],...
                'ButtonPushedFcn', @(btn,event) replayButtonPushed(app));
            app.viz.video.btn = btn;
            
            btn = uibutton(app.viz.video.fig,'push',...
                'Text','Save',...
                'Position',[200,10, 100, 20],...
                'ButtonPushedFcn', @(btn,event) videoSaveButtonPushed(app));
            app.viz.video.btn_save = btn;
            
        end
        
        function replayButtonPushed(app)
            playVideo(app);
        end
        
        function videoSaveButtonPushed(app)
            
            [vidfile,vidpath] = uiputfile("*.mp4");
            
            
            im_data = medfilt3(app.displ.filt_fft(:,:,:,app.viz.currentTap),[9 9 1]);
            
            
            t = 1;
            for w = 1:app.viz.Width
                %                 im_vel(:,w,:) = diff(im_data(:,w,:),1,3)*app.US.CollectionParams.fastFrameRate;
                im_vel(:,w,:) = im_data(:,w,:); % not velocity
            end
            
            
            him =  imagesc(app.viz.video.ax,app.viz.wx,app.viz.dx,im_vel(:,:,t));
            him.AlphaData = 0.35;
            
            caxis(app.viz.video.ax,[0 1e-7])
            ht =  title(app.viz.video.ax,sprintf('Time = %0.3f ms',t*0.05));
            
            h2 = title(app.viz.video.ax_im,sprintf('Time = %0.3f ms',t*0.05));
            h2.Color = [1 1 1];
            drawnow limitrate
            
            
            writerObj = VideoWriter(fullfile(vidpath,[vidfile]),'MPEG-4');
            writerObj.FrameRate = 5;
            %
            marg = 15;
            app.viz.video.ax.Units = 'pixels';
            pos = app.viz.video.ax.Position;
            rect = [marg, marg, pos(3)+2*marg, pos(4)+2*marg];
            %             marg = 0.05;
            %             pos = app.viz.video.ax.Position;
            %             rect = [-marg, -marg+0.1, pos(3)+2*marg, pos(4)+2*marg];
            open(writerObj);
            
            while isvalid(ht) && isvalid(him) && t < app.viz.Frames
                ht.String = sprintf('Time = %0.3f ms',t*0.05);
                him.CData = im_vel(:,:,t);
                
                drawnow
                
                frame = getframe(app.viz.video.ax,rect);
                writeVideo(writerObj,frame);
                t = t+1;
            end
            
            close(writerObj)
            app.viz.video.ax.Units = 'normalized';
            
        end
        
        function visualiseAllTaps(app)
            ncols = app.viz.Depth;
            sum_mask = sum(app.viz.ROImask,2);
            i_middle = round(mean(sum_mask(sum_mask>0))/2);
            sel_deps = app.viz.ROImask(:,i_middle);
            
            col_all = viridis(ncols);
            col = col_all(sel_deps,:);
            
            
            tp = 1:app.viz.nTaps;
            
            for t = tp
                SWS_ROI = app.displ.SWS_ROI{t};
                Rsq_ROI = app.displ.Rsq_ROI{t};
                
                
                ind_good = SWS_ROI > 0 & SWS_ROI < 200 &  Rsq_ROI > app.viz.sum_thresh;
                if all(ind_good == 0)
                    continue
                end
                hp = plot(app.axSWSoverTap,t,(SWS_ROI(ind_good)),'o','linestyle','-');
                set(hp,{'Markeredgecolor'},num2cell(col(ind_good,:),2),...
                    {'markerfacecolor'},num2cell(col(ind_good,:),2),...
                    {'markersize'},num2cell(ones(sum(ind_good),1)*4,2))
                
                hold(app.axSWSoverTap,'on')


            end
            
            colormap(app.axSWSoverTap,flipud(col))
            caxis(app.axSWSoverTap,[1 ncols])
            hc = colorbar(app.axSWSoverTap);
            hc.Ticks = [1 ncols];
            hc.TickLabels = { 'deep','superficial'};
            xlim(app.axSWSoverTap,[0 app.viz.nTaps])
            
            hold(app.axSWSoverTap,'off')
            
            
            %             linkaxes([app.axSWSoverTap app.axSWSoverTapLine])
            
        end
        
        function visualiseLineTaps(app)
            ncols = app.viz.Depth;
            %             sum_mask = sum(app.viz.ROImask,2);
            %             i_middle = round(mean(sum_mask(sum_mask>0))/2);
            %             sel_deps = app.viz.ROImask(:,i_middle);
            
            col_all = viridis(ncols);
            %             col = col_all(sel_deps,:);
            
            
            tp = 1:app.viz.nTaps;
            dc = app.viz.currentDepth;
            for t = tp
                if isnan(app.displ.SWS{t}(dc)) || app.displ.Rsq{t}(dc) < app.viz.sum_thresh
                    continue
                    
                end
                hp2 = plot(app.axSWSoverTapLine,t,(app.displ.SWS{t}(dc)),'o','linestyle','-');
                set(hp2,{'Markeredgecolor'},num2cell(col_all(dc,:),2),...
                    {'markerfacecolor'},num2cell(col_all(dc,:),2));
                
                
                %
                %                 ha = errorbar(app.axSWSoverTapLine,t,mean(-SWS_ROI{t}(ind_good)),std(-SWS_ROI{t}(ind_good)),'Marker','o');
                %                 set(ha,'Markeredgecolor',mean(col(ind_good,:),1),...
                %                     'markerfacecolor',mean(col(ind_good,:),1),...
                %                     'color',mean(col(ind_good,:),1),'LineWidth',2)
                hold(app.axSWSoverTapLine,'on')
            end
            tc = app.viz.currentTap;
            hpc = plot(app.axSWSoverTapLine,tc,(app.displ.SWS{tc}(dc)),'go','MarkerFaceColor','g','MarkerSize',10);
            
            colormap(app.axSWSoverTapLine,flipud(col_all))
            caxis(app.axSWSoverTapLine,[1 ncols])
            hc = colorbar(app.axSWSoverTapLine);
            hc.Ticks = [1 ncols];
            hc.TickLabels = { 'deep','superficial'};
            xlim(app.axSWSoverTapLine,[0 app.viz.nTaps])
            hold(app.axSWSoverTapLine,'off')
            
            %             linkaxes([app.axSWSoverTap app.axSWSoverTapLine])


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            
            %             app.flags.changeLine = 0; % if we are looking for a line that isn't flat along the whole width
            
            
        end

        % Callback function
        function LoadFileClicked(app, event)
            lampYellow(app)
            app = loadFiles(app);
            lampGreen(app)
        end

        % Value changing function: depthSlider
        function depthSliderValueChanging(app, event)
            % when the depth slider is being moved, it moves the red line
            % to help the user see where to align it
            lampYellow(app)
            changingValue = event.Value;
            
            Depth = app.viz.Depth;
            dDiff = app.viz.currentDepth-(Depth - changingValue);
            
            dx = app.viz.dx;
            
            app.viz.bmodeRedLine.YData = app.viz.linedx([1,end])- dDiff*diff(dx(1:2)); %app.viz.bmodeRedLine.YData - dDiff*diff(dx(1:2));
            app.viz.overlayRedLine.YData = app.viz.linedx([1,end])- dDiff*diff(dx(1:2));
            app.viz.SWSRedLine.YData  = app.viz.linedx([1,1])- dDiff*diff(dx(1:2));
            
            %
            %             app.viz.bmodeRedLine.YData = app.viz.bmodeRedLine.YData- dDiff*diff(dx(1:2)); %app.viz.bmodeRedLine.YData - dDiff*diff(dx(1:2));
            
        end

        % Value changed function: depthSlider
        function depthSliderValueChanged(app, event)
            lampRed(app)
            
            Depth = app.viz.Depth;
            value = Depth-app.depthSlider.Value;
            
            app.viz.currentDepth = round(value);
            if app.viz.currentDepth > Depth
                app.viz.currentDepth = Depth;
            elseif app.viz.currentDepth < 1
                app.viz.currentDepth = 1;
            end
            
            app = resetWaveSpeed(app);
            
            
            quickUpdateDisplay(app)
            lampGreen(app)
        end

        % Value changed function: TapSlider
        function TapSliderValueChanged(app, event)
            lampRed(app)
            value = app.TapSlider.Value;
            app.TapSlider.Value = round(value);
            app.viz.currentTap = app.TapSlider.Value;
            app.TapDisplay.Value = app.viz.currentTap;
            resetWaveSpeed(app)
            updateDisplay(app)
            lampGreen(app)
        end

        % Value changing function: startWidthSlider
        function startWidthSliderValueChanging(app, event)
            
            lampYellow(app)
            changingValue = event.Value;
            if changingValue + app.lengthSlider.Value < app.viz.wx(end)
                app.viz.bmodeRedLine.XData = [changingValue,changingValue + app.lengthSlider.Value];
                app.viz.overlayRedLine.XData = [changingValue,changingValue + app.lengthSlider.Value];
            else
                app.lengthSlider.Value = app.viz.wx(end)-changingValue;
                app.viz.bmodeRedLine.XData = [app.viz.wx(end)-app.lengthSlider.Value,app.viz.wx(end)];
                app.viz.overlayRedLine.XData = [app.viz.wx(end)-app.lengthSlider.Value,app.viz.wx(end)];
                
            end
            
        end

        % Value changed function: startWidthSlider
        function startWidthSliderValueChanged(app, event)
            lampRed(app)
            %             app.flags.changeLine = 1;
            if app.startWidthSlider.Value + app.lengthSlider.Value > app.viz.wx(end)
                app.lengthSlider.Value = app.viz.wx(end) - app.startWidthSlider.Value;
                
                %
                %                 value = app.lengthSlider.Value - app.US.Trans.spacingMm;
                %                 app.startWidthSlider.Value = value;
            end
            app.lengthField.Value  = app.lengthSlider.Value;
            app.viz.currentWidth = floor([ app.startWidthSlider.Value ,  (app.startWidthSlider.Value +app.lengthSlider.Value)]/app.US.Trans.spacingMm)+1;
            app.viz.currentWidthMM = [ app.startWidthSlider.Value , (app.startWidthSlider.Value + app.lengthSlider.Value)];
            app = resetWaveSpeed(app);
            updateDisplay(app)
            
            lampGreen(app)
        end

        % Value changed function: lengthField
        function lengthFieldValueChanged(app, event)
            value = app.lengthField.Value;
            app.lengthSlider.Value = value;
            lengthSliderValueChanged(app,event);
        end

        % Value changing function: lengthSlider
        function lengthSliderValueChanging(app, event)
            lampYellow(app)
            changingValue = event.Value;
            app.lengthField.Value = changingValue;
            if changingValue + app.startWidthSlider.Value < app.viz.wx(end)
                app.viz.bmodeRedLine.XData = [app.startWidthSlider.Value,app.startWidthSlider.Value+changingValue ];
                app.viz.overlayRedLine.XData = [app.startWidthSlider.Value,app.startWidthSlider.Value+changingValue];
            else
                app.startWidthSlider.Value = app.viz.wx(end)-changingValue;
                app.viz.bmodeRedLine.XData = [app.viz.wx(end)-changingValue,app.viz.wx(end)];
                app.viz.overlayRedLine.XData = [app.viz.wx(end)-changingValue,app.viz.wx(end)];
                
            end
            
        end

        % Value changed function: lengthSlider
        function lengthSliderValueChanged(app, event)
            lampRed(app)
            
            if app.startWidthSlider.Value + app.lengthSlider.Value > app.viz.wx(end)
                app.lengthSlider.Value = app.viz.wx(end) - app.startWidthSlider.Value ;
            end
            app.lengthField.Value  = app.lengthSlider.Value;
            app.viz.currentWidth = floor([ app.startWidthSlider.Value ,  (app.startWidthSlider.Value +app.lengthSlider.Value)]/app.US.Trans.spacingMm)+1;
            app.viz.currentWidthMM = [ app.startWidthSlider.Value , (app.startWidthSlider.Value + app.lengthSlider.Value)];
            app = resetWaveSpeed(app);
            updateDisplay(app)
            lampGreen(app)
        end

        % Value changed function: RotationSpinner
        function RotationSpinnerValueChanged(app, event)
            lampRed(app)
            resetWaveSpeed(app)
            quickUpdateDisplay(app)
            lampGreen(app)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            lampRed(app)
            key = event.Key;
            Depth = app.viz.Depth;
            switch key
                case 'rightarrow'
                    
                    app.TapSlider.Value = round(app.TapSlider.Value + 1);
                    if app.TapSlider.Value > app.viz.nTaps
                        app.TapSlider.Value = app.viz.nTaps;
                    end
                    app.viz.currentTap = app.TapSlider.Value;
                    app.TapDisplay.Value = app.viz.currentTap;
                    resetWaveSpeed(app)
                    updateDisplay(app)
                    
                case 'leftarrow'
                    
                    app.TapSlider.Value = round(app.TapSlider.Value - 1);
                    if app.TapSlider.Value < 1
                        app.TapSlider.Value = 1;
                    end
                    app.viz.currentTap = app.TapSlider.Value;
                    app.TapDisplay.Value = app.viz.currentTap;
                    resetWaveSpeed(app)
                    updateDisplay(app)
                case 'downarrow'
                    
                    value = Depth-app.depthSlider.Value + 1;
                    if value > Depth
                        value = Depth;
                        app.depthSlider.Value = app.depthSlider.Value;
                    else
                        app.depthSlider.Value = app.depthSlider.Value - 1;
                    end
                    app.viz.currentDepth = round(value);
                    if app.flags.peakTimes(app.viz.currentTap) == 1
                        app = resetWaveSpeed(app);
                    end
                    quickUpdateDisplay(app)
                case 'uparrow'
                    value = Depth-app.depthSlider.Value-1;
                    if value < 1
                        value = 1;
                        app.depthSlider.Value = app.depthSlider.Value ;
                    else
                        app.depthSlider.Value = app.depthSlider.Value + 1;
                        
                    end
                    app.viz.currentDepth = round(value);
                    if app.flags.peakTimes(app.viz.currentTap) == 1
                        app = resetWaveSpeed(app);
                    end
                    quickUpdateDisplay(app)
            end
            lampGreen(app)
        end

        % Button pushed function: ShowvideoButton
        function ShowvideoButtonPushed(app, event)
            
            app = buttonPlot(app);
            playVideo(app)
            
        end

        % Menu selected function: LoadMenu
        function LoadMenuSelected(app, event)
            lampYellow(app)
            app = loadFiles(app);
            lampGreen(app)
        end

        % Menu selected function: SelectROIDepthsMenu
        function SelectROIDepthsMenuSelected(app, event)
            
            lampYellow(app)
            hline = drawline(app.axImBmode);
            tp = app.viz.currentTap;
            lat_pos = mean(hline.Position(:,1)); % find the approximate lateral position;
            Ix = find(app.viz.linewx > lat_pos,1,'first');
            app.viz.diffx = diff(app.viz.dx(1:2)) ;
            % see if there's a depth change between where the user selected the depth line and the left most point of the line
            d_add = app.viz.linedI(Ix) - app.viz.linedI(1);
            
            depth_lims = hline.Position(:,2); % the top and bottom of the line
            depth_lims = sort(depth_lims);
            
            Iv = find(app.viz.dx > depth_lims(1),1,'first') ;
            Iv2 = find(app.viz.dx > depth_lims(2),1,'first'); % index of depth
            if isempty(Iv)
                Iv = 1;
            else
                Iv = Iv - d_add;
            end
            if isempty(Iv2)
                Iv2 = app.viz.Depth;
            elseif Iv2 > app.viz.Depth % When there are less pixels (lower res), the fact that the displacement
                %                 is averaged over 5 depth points starts to matter because
                %                 we lose the end five points
                Iv2 = app.viz.Depth;
            else
                Iv2 = Iv2 - d_add;
            end
            addedDepth = Iv2 - Iv;
            maxDepth = max(app.viz.linedI);
            if maxDepth + addedDepth > app.viz.Depth
                addedDepth =app.viz.Depth - maxDepth +1;
                %                 Iv2 = app.viz.Depth; % largest possible depth without exceeding the frame
            end
            
            app.depthSlider.Value = app.viz.Depth - Iv ;
            depthSliderValueChanged(app,[]);
            app.viz.addedDepth = addedDepth;
            %             app.viz.currentDepth = depth_lims(1)
            %             fill(app.axImBmode,[app.viz.linewx,fliplr(app.viz.linewx)],[app.viz.linedx,fliplr(app.viz.linedx+addedDepth*app.viz.diffx)],'r',"FaceAlpha",0.5,'EdgeColor',"none")
            
            
            app.viz.ROImask = false(app.viz.Depth,app.viz.Width); % set up a mask in pixel world (IQ) to know where we are
            
            rI = 1;
            for dd = 0 : addedDepth % for each index of depth selected
                for ii = 1:length(app.viz.linewI)
                    
                    app.viz.ROImask(app.viz.linedI(ii) + dd ,app.viz.linewI(ii)) = 1;
                    
                end
                
                app.viz.dxROI(rI) = app.viz.linedx(1)+app.viz.diffx*dd;
                
                rI = rI+1;
            end
            
            app.viz.ROIcorners = [app.viz.linewx([1,end]), fliplr(app.viz.linewx([1,end]))  ;...
                app.viz.linedx([1,end]), fliplr(app.viz.linedx([1,end]))+app.viz.diffx*addedDepth]; % [x1, x2, x3, x4; y1, y2, y3, y4] in mm


            app = calculateROIWaveSpeed(app);
            
            updateDisplay(app)
            lampGreen(app)
        end

        % Menu selected function: ClearROIMenu
        function ClearROIMenuSelected(app, event)
            lampRed(app)
            app.displ = rmfield(app.displ,'SWS_ROI');
            %             app.displ = rmfield(app.displ,'Rsq_ROI');
            %             app.displ = rmfield(app.displ,'wat_ROI');
            %             app.displ = rmfield(app.displ,'mdl_ROI');
            app.viz = rmfield(app.viz,'dxROI');
            app.viz = rmfield(app.viz,'ROImask');
            app.viz = rmfield(app.viz,'ROIcorners');
            app.flags.ROI = 0;
            updateDisplay(app)
            lampGreen(app)
        end

        % Menu selected function: SaveROIMenu
        function SaveROIMenuSelected(app, event)
            if app.flags.ROI == 0
                warndlg('No ROI selected.')
                return
            end
            
            lampRed(app)
            
            % does the user want to save the ROI from the current tap, or
            % all the taps?
            msg= 'Do you want to save out all taps or the current tap?';
            answer = uiconfirm(app.UIFigure,msg,'Save Options',...
                'Options',{'Current Tap', 'All Taps','Cancel'},...
                'DefaultOption',1,...
                'CancelOption',3);
            
            switch answer
                case 'Current Tap'
                    tp = app.viz.currentTap;
                case 'All Taps'
                    tp = 1:app.viz.nTaps;
                otherwise
                    return
            end
            
            t_ind = 1;
            for t = tp
                SWS_ROI{t_ind} = app.displ.SWS_ROI{t};
                Rsq_ROI{t_ind} = app.displ.Rsq_ROI{t}; % Rsquared confidence in the linear fit
                wat_ROI{t_ind} = app.displ.wat_ROI{t};

                %             mdl = app.displ.mdl_ROI{t};
                t_ind = t_ind+1;
            end
            
            dxROI = app.viz.dxROI;
            ROImask = app.viz.ROImask;
            ROIcorners = app.viz.ROIcorners;
            
            
            [path,file,ext] = fileparts(app.US.filename);
            %             strs = strsplit(path,filesep);
            
            filename_default = [file,'_ .mat'];
            
            [file,path] = uiputfile( '*.mat','Save ROI data',fullfile(app.US.path,filename_default));
            if file ~= 0
                save(fullfile(path,file),'SWS_ROI','Rsq_ROI',"wat_ROI","ROIcorners","ROImask","dxROI");
                msgbox(sprintf('File saved as: %s',fullfile(path,file)));
            end
            
            lampGreen(app)
        end

        % Menu selected function: VisualiseROIAllTapsMenu
        function VisualiseROIAllTapsMenuSelected(app, event)
            lampRed(app)
            if app.flags.ROI == 0
                warndlg('No ROI selected.')
                return
            end
            
            lampGreen(app)
        end

        % Value changed function: SumthresholdEditField
        function SumthresholdEditFieldValueChanged(app, event)
            value = app.SumthresholdEditField.Value;
            app.viz.sum_thresh = value;
            updateDisplay(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'parula');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.LoadMenu = uimenu(app.FileMenu);
            app.LoadMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadMenuSelected, true);
            app.LoadMenu.Text = 'Load';
            app.ROIMenu = uimenu(app.UIFigure);
            app.ROIMenu.Text = 'ROI';
            app.SelectROIDepthsMenu = uimenu(app.ROIMenu);
            app.SelectROIDepthsMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectROIDepthsMenuSelected, true);
            app.SelectROIDepthsMenu.Text = 'Select depths';
            app.ClearROIMenu = uimenu(app.ROIMenu);
            app.ClearROIMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearROIMenuSelected, true);
            app.ClearROIMenu.Text = 'Clear';
            app.SaveROIMenu = uimenu(app.ROIMenu);
            app.SaveROIMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveROIMenuSelected, true);
            app.SaveROIMenu.Text = 'Save';
            app.VisualiseROIAllTapsMenu = uimenu(app.ROIMenu);
            app.VisualiseROIAllTapsMenu.MenuSelectedFcn = createCallbackFcn(app, @VisualiseROIAllTapsMenuSelected, true);
            app.VisualiseROIAllTapsMenu.Text = 'Visualise all taps';
            app.axImBmode = uiaxes(app.UIFigure);
            title(app.axImBmode, 'B-mode')
            xlabel(app.axImBmode, 'Width (mm)')
            ylabel(app.axImBmode, 'Depth (mm)')
            zlabel(app.axImBmode, 'Z')
            app.axImBmode.YTick = [0 0.5 1];
            app.axImSWS = uiaxes(app.UIFigure);
            xlabel(app.axImSWS, 'Width (mm)')
            ylabel(app.axImSWS, 'Depth (mm)')
            zlabel(app.axImSWS, 'Z')
            app.axImSWS.YTick = [0 0.5 1];
            app.axDispWidth = uiaxes(app.UIFigure);
            title(app.axDispWidth, 'Displacement over width')
            xlabel(app.axDispWidth, 'Time (ms)')
            ylabel(app.axDispWidth, 'Width (mm)')
            zlabel(app.axDispWidth, 'Z')
            app.axDisp = uiaxes(app.UIFigure);
            title(app.axDisp, 'Displacement over width')
            xlabel(app.axDisp, 'Time (ms)')
            ylabel(app.axDisp, 'Displacement (um)')
            zlabel(app.axDisp, 'Z')
            app.axSWSoverTap = uiaxes(app.UIFigure);
            title(app.axSWSoverTap, 'Title')
            xlabel(app.axSWSoverTap, 'Tap #')
            ylabel(app.axSWSoverTap, 'SWS')
            zlabel(app.axSWSoverTap, 'Z')
            app.axSWSoverTapLine = uiaxes(app.UIFigure);
            title(app.axSWSoverTapLine, 'Title')
            xlabel(app.axSWSoverTapLine, 'Tap #')
            ylabel(app.axSWSoverTapLine, 'SWS')
            zlabel(app.axSWSoverTapLine, 'Z')
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = '';
            app.depthSlider = uislider(app.UIFigure);
            app.depthSlider.MajorTicks = [0 25 50 75 100];
            app.depthSlider.MajorTickLabels = {'Superficial', '', '', '', 'Deep'};
            app.depthSlider.ValueChangedFcn = createCallbackFcn(app, @depthSliderValueChanged, true);
            app.depthSlider.ValueChangingFcn = createCallbackFcn(app, @depthSliderValueChanging, true);
            app.TapSliderLabel = uilabel(app.UIFigure);
            app.TapSliderLabel.Text = 'Tap #:';
            app.TapSlider = uislider(app.UIFigure);
            app.TapSlider.Limits = [1 10];
            app.TapSlider.ValueChangedFcn = createCallbackFcn(app, @TapSliderValueChanged, true);
            app.TapSlider.Value = 1;
            app.CurrentfileEditFieldLabel = uilabel(app.UIFigure);
            app.CurrentfileEditFieldLabel.Text = 'Current file:';
            app.CurrentfileEditField = uieditfield(app.UIFigure, 'text');
            app.CurrentfileEditField.Editable = 'off';
            app.RotationSpinnerLabel = uilabel(app.UIFigure);
            app.RotationSpinnerLabel.Text = 'Rotation';
            app.RotationSpinner = uispinner(app.UIFigure);
            app.RotationSpinner.Limits = [-89 89];
            app.RotationSpinner.RoundFractionalValues = 'on';
            app.RotationSpinner.ValueChangedFcn = createCallbackFcn(app, @RotationSpinnerValueChanged, true);
            app.StartwidthSliderLabel = uilabel(app.UIFigure);
            app.StartwidthSliderLabel.Text = 'Start width';
            app.startWidthSlider = uislider(app.UIFigure);
            app.startWidthSlider.ValueChangedFcn = createCallbackFcn(app, @startWidthSliderValueChanged, true);
            app.startWidthSlider.ValueChangingFcn = createCallbackFcn(app, @startWidthSliderValueChanging, true);
            app.LengthLabel = uilabel(app.UIFigure);
            app.LengthLabel.Text = 'Length';
            app.lengthSlider = uislider(app.UIFigure);
            app.lengthSlider.ValueChangedFcn = createCallbackFcn(app, @lengthSliderValueChanged, true);
            app.lengthSlider.ValueChangingFcn = createCallbackFcn(app, @lengthSliderValueChanging, true);
            app.Lamp = uilamp(app.UIFigure);
            app.mmEditFieldLabel = uilabel(app.UIFigure);
            app.mmEditFieldLabel.Text = 'mm';
            app.lengthField = uieditfield(app.UIFigure, 'numeric');
            app.lengthField.Limits = [0 Inf];
            app.lengthField.ValueChangedFcn = createCallbackFcn(app, @lengthFieldValueChanged, true);
            app.lengthField.Value = 20;
            app.ShowvideoButton = uibutton(app.UIFigure, 'push');
            app.ShowvideoButton.ButtonPushedFcn = createCallbackFcn(app, @ShowvideoButtonPushed, true);
            app.ShowvideoButton.Text = 'Show video';
            app.TapDisplay = uieditfield(app.UIFigure, 'numeric');
            app.TapDisplay.Editable = 'off';
            app.SumthresholdEditFieldLabel = uilabel(app.UIFigure);
            app.SumthresholdEditFieldLabel.Text = 'Sum threshold';
            app.SumthresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.SumthresholdEditField.ValueChangedFcn = createCallbackFcn(app, @SumthresholdEditFieldValueChanged, true);
            app.SumthresholdEditField.Value = 25;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualiseHFR_Radon

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end